classdef Graficas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GridLayout    matlab.ui.container.GridLayout
        GridLayout2   matlab.ui.container.GridLayout
        UIAxes        matlab.ui.control.UIAxes
        UIAxes2       matlab.ui.control.UIAxes
        UIAxes3       matlab.ui.control.UIAxes
        UIAxes4       matlab.ui.control.UIAxes
        UIAxes5       matlab.ui.control.UIAxes
        UIAxes6       matlab.ui.control.UIAxes
        GridLayout3   matlab.ui.container.GridLayout
        InicioButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function JugarButtonPushed(app, event)
            metricas;
            closereq ();
        end

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)
            Inicio;
            closereq ();
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            %componente x de puntos originales
xHF = 0:0.1:1;

%componente y de puntos originales
yHF = [40,42,45,50,56,60,56,50,45,42,40];

yREF1 = [0,0,0,0,0,0,0,0,0,0,0];
yREF2 = [100,100,100,100,100,100,100,100,100,100,100];
%comonente x de evaluacion
xRHF = 0:0.001:1;

%Para quitar los picos
pp= makima(xHF,yHF);

m= ppval(pp, xRHF);

plot(app.UIAxes,xHF,yREF1);hold on
plot(app.UIAxes,xHF,yREF2);hold on
plot(app.UIAxes,xRHF,m); 


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Flexion Hombro')
            xlabel(app.UIAxes, 'Frames')
            ylabel(app.UIAxes, 'Grados')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, 'Flexion Codo')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout2);
            title(app.UIAxes3, 'Flexion Muñeca')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout2);
            title(app.UIAxes4, 'Abduccion Hombro')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.GridLayout2);
            title(app.UIAxes5, 'Supinacion Codo')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.GridLayout2);
            title(app.UIAxes6, 'Abduccion Muñeca')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.InicioButton = uibutton(app.GridLayout3, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Text = 'Inicio';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Graficas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end