classdef GraficasTiempo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        GridLayout3              matlab.ui.container.GridLayout
        ResultadosButton         matlab.ui.control.Button
        GridLayout2              matlab.ui.container.GridLayout
        IniciarmovimientoButton  matlab.ui.control.Button
        AMuneca                  matlab.ui.control.UIAxes
        FMuneca                  matlab.ui.control.UIAxes
        SCodo                    matlab.ui.control.UIAxes
        FCodo                    matlab.ui.control.UIAxes
        AHombro                  matlab.ui.control.UIAxes
        FHombro                  matlab.ui.control.UIAxes
    end


    methods (Access = private)

        function [xnHA,ynHA,mHA,xRHA] = AleatoriedadAbdHombro (app,noise)
            %Cracion grafica
            xHA = 0:0.1:1;
            xnHA = xHA+ 0,01*rand(1,11);
            xnHA(1) = 0;
            xnHA(end)=1;
            xRHA = 0:0.001:1;
            yHA = [10,14,19,26,31,33,30,26,21,16,10];
            ynHA = yHA + 5*rand(1,11);
            ynHA(1) = yHA(1);
            ynHA(end) = yHA(end);
            maxim= 4;
            minim= 1;% el rango pongo el que vea apropiado
            duration = minim+(maxim-minim)*rand(1);
            ppHA= makima(xnHA*duration,ynHA);
            mHA= ppval(ppHA, xRHA*duration);

            if noise == 1

                mHA = mHA + 3*rand(1,1001);

                %Filtrado
                xHAFiltrar = xRHA*duration;
                yHAFiltrar = mHA;
                [Asgolay,window] = smoothdata(yHAFiltrar,'sgolay');
                %plot(app.AHombro,xHAFiltrar,Asgolay,'r')

                i = 1;
                while i<length(xHAFiltrar)
                    plot(app.AHombro,xHAFiltrar(1:i),Asgolay(1:i))
                    i = i+4;
                    pause(0.01)
                end

                %Guardar variables en el workspace
                %assignin('base','nombre wokspace',variable)
                assignin('base','xHAFiltrar',xHAFiltrar)
                assignin('base','Asgolay',Asgolay)
                x = xHAFiltrar';
                y = Asgolay';

                %Escribir en un excel
                T = table (x, y);
                assignin('base','T',T)

                L = {'Variable x','Variable y'};
                C = table2cell(T);
                assignin('base','L',L)
                assignin('base','C',C)

                %Concateno en la matriz cell la legenda de cada columna
                A = [L;C];
                assignin('base','A',A)

                %crea y reescribe
                xlswrite('datosHA.xlsx',A,'xlswrite','A2');


            end
        end

        function [xnCS,ynCS,mCS,xRCS] =  AleatoriedadSCodo(app,noise)

            %Creacion grafica
            xCS = 0:0.1:1;
            xnCS = xCS+ 0,01*rand(1,11);
            xnCS(1) = 0;
            xnCS(end)=1;
            xRCS = 0:0.001:1;
            yCS = [25,28,35,42,52,51,54,25,17,10,25];
            ynCS = yCS + 5*rand(1,11);
            ynCS(1) = yCS(1);
            ynCS(end) = yCS(end);
            maxim= 1.10;
            minim= 0.8;
            duration = minim+(maxim-minim)*rand(1);
            ppCS= makima(xnCS*duration,ynCS);
            mCS= ppval(ppCS, xRCS*duration);


            if noise == 1
                mCS = mCS + 3*rand(1,1001);
                %Filtrado
                xCSFiltrar = xRCS*duration;
                yCSFiltrar = mCS;
                [Asgolay,window] = smoothdata(yCSFiltrar,'sgolay');
                %plot(app.SCodo,xCSFiltrar,Asgolay,'r')

                i = 1;
                while i<length(xCSFiltrar)
                    plot(app.SCodo,xCSFiltrar(1:i),Asgolay(1:i))
                    i = i+4;
                    pause(0.01)
                end

                %Guardar variables en el workspace
                %assignin('base','nombre wokspace',variable)
                assignin('base','xCSFiltrar',xCSFiltrar)
                assignin('base','Asgolay',Asgolay)
                x = xCSFiltrar';
                y = Asgolay';

                %Escribir en un excel
                T = table (x, y);
                assignin('base','T',T)

                L = {'Variable x','Variable y'};
                C = table2cell(T);
                assignin('base','L',L)
                assignin('base','C',C)

                %Concateno en la matriz cell la legenda de cada columna
                A = [L;C];
                assignin('base','A',A)

                %crea y reescribe
                xlswrite('datosCS.xlsx',A,'xlswrite','A2');

            end
        end


        function [xnCF,ynCF,mCF,xRCF] = AleatoriedadFCodo(app,noise)
            %Creacion grafica
            xCF = 0:0.1:1;
            xnCF = xCF+ 0,01*rand(1,11);
            xnCF(1) = 0;
            xnCF(end)=1;
            xRCF = 0:0.001:1;
            yCF = [67,70,89,108,115,120,115,107,83,72,67];
            ynCF = yCF + 5*rand(1,11);
            ynCF(1) = yCF(1);
            ynCF(end) = yCF(end);
            maxim= 1.10;
            minim= 0.8;
            duration = minim+(maxim-minim)*rand(1);
            ppCF= makima(xnCF*duration,ynCF);
            mCF= ppval(ppCF, xRCF*duration);

            if noise == 1
                mCF = mCF + 3*rand(1,1001);
                %Filtrado
                xCFFiltrar = xRCF*duration;
                yCFFiltrar = mCF;
                [Asgolay,window] = smoothdata(yCFFiltrar,'sgolay');
                %plot(app.FCodo,xCFFiltrar,Asgolay,'r')

                i = 1;
                while i<length(xCFFiltrar)
                    plot(app.FCodo,xCFFiltrar(1:i),Asgolay(1:i))
                    i = i+4;
                    pause(0.01)
                end

                %Guardar variables en el workspace
                %assignin('base','nombre wokspace',variable)
                assignin('base','xCFFiltrar',xCFFiltrar)
                assignin('base','Asgolay',Asgolay)
                x = xCFFiltrar';
                y = Asgolay';

                %Escribir en un excel
                T = table (x, y);
                assignin('base','T',T)

                L = {'Variable x','Variable y'};
                C = table2cell(T);
                assignin('base','L',L)
                assignin('base','C',C)

                %Concateno en la matriz cell la legenda de cada columna
                A = [L;C];
                assignin('base','A',A)

                %crea y reescribe
                xlswrite('datosCF.xlsx',A,'xlswrite','A2');

            end
        end
        function [xnMF,ynMF,mMF,xRMF] = AleatoriedadFMuneca(app,noise)
            %Creacion grafica
            xMF = 0:0.1:1;
            xnMF = xMF+ 0,01*rand(1,11);
            xnMF(1) = 0;
            xnMF(end)=1;
            xRMF = 0:0.001:1;
            yMF = [27,25,23,20,21,20,22,20,23,25,27];
            ynMF = yMF + 5*rand(1,11);
            ynMF(1) = yMF(1);
            ynMF(end) = yMF(end);
            maxim= 1.10;
            minim= 0.8;%el rango pongo el que vea apropiado
            duration = minim+(maxim-minim)*rand(1);
            ppMF= makima(xnMF*duration,ynMF);
            mMF= ppval(ppMF, xRMF*duration);

            if noise == 1
                mMF = mMF + 3*rand(1,1001);
                %Filtrado
                xMFFiltrar = xRMF*duration;
                yMFFiltrar = mMF;
                [Asgolay,window] = smoothdata(yMFFiltrar,'sgolay');
                % plot(app.FMuneca,xMFFiltrar,Asgolay,'r')

                i = 1;
                while i<length(xMFFiltrar)
                    plot(app.FMuneca,xMFFiltrar(1:i),Asgolay(1:i))
                    i = i+4;
                    pause(0.01)
                end


                %Guardar variables en el workspace
                %assignin('base','nombre wokspace',variable)
                assignin('base','xMFFiltrar',xMFFiltrar)
                assignin('base','Asgolay',Asgolay)
                x = xMFFiltrar';
                y = Asgolay';

                %Escribir en un excel
                T = table (x, y);
                assignin('base','T',T)

                L = {'Variable x','Variable y'};
                C = table2cell(T);
                assignin('base','L',L)
                assignin('base','C',C)

                %Concateno en la matriz cell la legenda de cada columna
                A = [L;C];
                assignin('base','A',A)

                %crea y reescribe
                xlswrite('datosMF.xlsx',A,'xlswrite','A2');

            end
        end

        function [xnMA,ynMA,mMA,xRMA] = AleatoriedadAbdMuneca(app,noise)
            %Creacion grafica
            xMA = 0:0.1:1;
            xnMA = xMA+ 0,01*rand(1,11);
            xnMA(1) = 0;
            xnMA(end)=1;
            xRMA = 0:0.001:1;
            yMA = [5,6,10,12,15,16,15,12,9,6,5];
            ynMA = yMA + 5*rand(1,11);
            ynMA(1) = yMA(1);
            ynMA(end) = yMA(end);
            maxim= 1.10;
            minim= 0.8;
            duration = minim+(maxim-minim)*rand(1);
            ppMA= makima(xnMA*duration,ynMA);
            mMA= ppval(ppMA, xRMA*duration);

            if noise == 1
                mMA = mMA + 3*rand(1,1001);
                %Filtrado
                xMAFiltrar = xRMA*duration;
                yMAFiltrar = mMA;
                [Asgolay,window] = smoothdata(yMAFiltrar,'sgolay');
                %plot(app.AMuneca,xMAFiltrar,Asgolay,'r')

                i = 1;
                while i<length(xMAFiltrar)
                    plot(app.AMuneca,xMAFiltrar(1:i),Asgolay(1:i))
                    i = i+4;
                    pause(0.01)
                end

                %Guardar variables en el workspace
                %assignin('base','nombre wokspace',variable)
                assignin('base','xMAFiltrar',xMAFiltrar)
                assignin('base','Asgolay',Asgolay)
                x = xMAFiltrar';
                y = Asgolay';

                %Escribir en un excel
                T = table (x, y);
                assignin('base','T',T)

                L = {'Variable x','Variable y'};
                C = table2cell(T);
                assignin('base','L',L)
                assignin('base','C',C)

                %Concateno en la matriz cell la legenda de cada columna
                A = [L;C];
                assignin('base','A',A)

                %crea y reescribe
                xlswrite('datosMA.xlsx',A,'xlswrite','A2');
                        end
        end

        function [xnHF,ynHF,mHF,xRHF] = AleatoriedadFHombro (app, noise)
            %Creacion de la grafica
            xHF = 0:0.1:1;
            xnHF = xHF+ 0,01*rand(1,11);
            xnHF(1) = 0;
            xnHF(end)=1;
            xRHF = 0:0.001:1;
            yHF = [40,42,45,50,56,60,56,50,45,42,40];
            ynHF = yHF + 3*rand(1,11);
            ynHF(1) = yHF(1);
            ynHF(end) = yHF(end);
            maxim= 1.10;
            minim= 0.8;%el rango pongo el que vea apropiado
            duration = minim+(maxim-minim)*rand(1);
            ppHF= makima(xnHF*duration,ynHF);
            mHF= ppval(ppHF, xRHF*duration);

            if noise == 1
                mHF = mHF + 3*rand(1,1001);
                %Filtrado
                xHFFiltrar = xRHF*duration;
                yHFFiltrar = mHF;
                [Asgolay,window] = smoothdata(yHFFiltrar,'sgolay');
                %plot(app.FHombro,xHFFiltrar,Asgolay,'r')

                i = 1;
                while i<length(xHFFiltrar)
                    plot(app.FHombro,xHFFiltrar(1:i),Asgolay(1:i))
                    i = i+4;
                    pause(0.01)
                end

                %Guardar variables en el workspace
                %assignin('base','nombre wokspace',variable)
                assignin('base','xHFFiltrar',xHFFiltrar)
                assignin('base','Asgolay',Asgolay)
                x = xHFFiltrar';
                y = Asgolay';

                %Escribir en un excel
                T = table (x, y);
                assignin('base','T',T)

                L = {'Variable x','Variable y'};
                C = table2cell(T);
                assignin('base','L',L)
                assignin('base','C',C)

                %Concateno en la matriz cell la legenda de cada columna
                A = [L;C];
                assignin('base','A',A)

                %crea y reescribe
                xlswrite('datosHF.xlsx',A,'xlswrite','A2');

            end


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarmovimientoButton
        function IniciarmovimientoButtonPushed(app, event)
            AleatoriedadAbdHombro (app,1)
            AleatoriedadFHombro (app,1)
            AleatoriedadAbdMuneca (app,1)
            AleatoriedadFMuneca (app,1)
            AleatoriedadFCodo (app,1)
            AleatoriedadSCodo (app,1)

        end

        % Button pushed function: ResultadosButton
        function ResultadosButtonPushed(app, event)
            Tiempo;
            closereq ();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FHombro = uiaxes(app.GridLayout);
            title(app.FHombro, {'Flexión Hombro'; ''})
            xlabel(app.FHombro, 'X')
            ylabel(app.FHombro, 'Y')
            zlabel(app.FHombro, 'Z')
            app.AHombro = uiaxes(app.GridLayout);
            title(app.AHombro, {'Abduccióm Hombro'; ''})
            xlabel(app.AHombro, 'X')
            ylabel(app.AHombro, 'Y')
            zlabel(app.AHombro, 'Z')
            app.FCodo = uiaxes(app.GridLayout);
            title(app.FCodo, {'Flexión Codo'; ''})
            xlabel(app.FCodo, 'X')
            ylabel(app.FCodo, 'Y')
            zlabel(app.FCodo, 'Z')
            app.SCodo = uiaxes(app.GridLayout);
            title(app.SCodo, {'Supinación Codo'; ''})
            xlabel(app.SCodo, 'X')
            ylabel(app.SCodo, 'Y')
            zlabel(app.SCodo, 'Z')
            app.FMuneca = uiaxes(app.GridLayout);
            title(app.FMuneca, 'Flexión Muñeca')
            xlabel(app.FMuneca, 'X')
            ylabel(app.FMuneca, 'Y')
            zlabel(app.FMuneca, 'Z')
            app.AMuneca = uiaxes(app.GridLayout);
            title(app.AMuneca, {'Abducción Muñeca'; ''})
            xlabel(app.AMuneca, 'X')
            ylabel(app.AMuneca, 'Y')
            zlabel(app.AMuneca, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.IniciarmovimientoButton = uibutton(app.GridLayout2, 'push');
            app.IniciarmovimientoButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarmovimientoButtonPushed, true);
            app.IniciarmovimientoButton.Text = {'Iniciar movimiento'; ''};
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ResultadosButton = uibutton(app.GridLayout3, 'push');
            app.ResultadosButton.ButtonPushedFcn = createCallbackFcn(app, @ResultadosButtonPushed, true);
            app.ResultadosButton.Text = {'Resultados'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraficasTiempo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end