classdef Inicio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GraficasButton  matlab.ui.control.Button
        ProgresoButton  matlab.ui.control.Button
        JugarButton     matlab.ui.control.Button
        UIAxes          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%{
%CREAMOS EL AVATAR
f = [1 2 3 4; 5 6 7 8;1 2 6 5;2 3 7 6;3 4 8 7;4 1 5 8];
color = [1 0 0;0 1 0;0 0 1;1 1 0;1 0 1;1 1 1];
torso = [30,-10,10;30,40,10;90,40,10;90,-10,10;30,-10,120;30,40,120;90,40,120;90,-10,120];
brazo = [10,0,60;10,30,60;30,30,60;30,0,60;10,0,120;10,30,120;30,30,120;30,0,120];
antebrazo = [12,5,10;12,25,10;28,25,10;28,5,10;12,5,60;12,25,60;28,25,60;28,5,60];
mano = [10,10,-10;10,20,-10;30,20,-10;30,10,-10;10,10,10;10,20,10;30,20,10;30,10,10];
cuello = [50,0,120;50,30,120;70,30,120;70,0,120;50,0,140;50,30,140;70,30,140;70,0,140];

cabeza = [30,-10,140;30,40,140;90,40,140;90,-10,140;30,-10,180;30,40,180;90,40,180;90,-10,180];

%figure
patch(app.UIAxes,'faces',f,'vertices',torso,'FaceVertexCData',color,'FaceColor','flat')
patch(app.UIAxes,'faces',f,'vertices',brazo,'FaceVertexCData',color,'FaceColor','flat')
patch(app.UIAxes,'faces',f,'vertices',antebrazo,'FaceVertexCData',color,'FaceColor','flat')
patch(app.UIAxes,'faces',f,'vertices',mano,'FaceVertexCData',color,'FaceColor','flat')
patch(app.UIAxes,'faces',f,'vertices',cuello,'FaceVertexCData',color,'FaceColor','flat')
patch(app.UIAxes,'faces',f,'vertices',cabeza,'FaceVertexCData',color,'FaceColor','flat')

view(app.UIAxes,[-5,2])
%}
            CreacionAvatar([0,10,-40],[25,0,-67],[0,5,-27]); 
pause(1); 
closereq();
CreacionAvatar([0,14,-42],[28,0,-70],[0,6,-25]);
pause(1);
closereq();
 CreacionAvatar([0,19,-45],[35,0,-89],[0,10,-23]);
 pause(1);
 closereq();
 CreacionAvatar([0,26,-50],[42,0,-108],[0,12,-20]);
 pause(1);
 closereq();
 CreacionAvatar([0,31,-56],[52,0,-115],[0,15,-21]); 
 pause(1);
 closereq();
 CreacionAvatar([0,33,-60],[51,0,-120],[0,16,-20]); 
 pause(1);
 closereq();
 CreacionAvatar([0,30,-56],[54,0,-115],[0,15,-22]); 
 pause(1);
 closereq();
 CreacionAvatar([0,26,-50],[25,0,-107],[0,12,-20]); 
pause(1);
closereq();
CreacionAvatar([0,21,-45],[17,0,-83],[0,9,-23]); 
 pause(1);
 closereq();
CreacionAvatar([0,16,-42],[10,0,-72],[0,6,-25]);
pause(1);
closereq();
CreacionAvatar([0,10,-40],[25,0,-67],[0,5,-27]); 
pause(1);

        end

        % Button pushed function: JugarButton
        function JugarButtonPushed(app, event)
            metricas;
            closereq ();
        end

        % Button pushed function: GraficasButton
        function GraficasButtonPushed(app, event)
            Graficas;
            closereq ();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Avatar'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.JugarButton = uibutton(app.UIFigure, 'push');
            app.JugarButton.ButtonPushedFcn = createCallbackFcn(app, @JugarButtonPushed, true);
            app.JugarButton.Text = 'Jugar';
            app.ProgresoButton = uibutton(app.UIFigure, 'push');
            app.ProgresoButton.Text = 'Progreso';
            app.GraficasButton = uibutton(app.UIFigure, 'push');
            app.GraficasButton.ButtonPushedFcn = createCallbackFcn(app, @GraficasButtonPushed, true);
            app.GraficasButton.Text = 'Graficas';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Inicio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end