classdef Tiempo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        GridLayout4            matlab.ui.container.GridLayout
        VolverajugarButton     matlab.ui.control.Button
        GridLayout3            matlab.ui.container.GridLayout
        EscogerjuegoButton     matlab.ui.control.Button
        GridLayout2            matlab.ui.container.GridLayout
        MostrarsubdivisindemovimientosCheckBox  matlab.ui.control.CheckBox
        MovimientoButtonGroup  matlab.ui.container.ButtonGroup
        AbduccionMuecaButton   matlab.ui.control.RadioButton
        FlexionMunecaButton    matlab.ui.control.RadioButton
        SupinacionCodoButton   matlab.ui.control.RadioButton
        FlexionCodoButton      matlab.ui.control.RadioButton
        AbduccionHombroButton  matlab.ui.control.RadioButton
        FlexionHombroButton    matlab.ui.control.RadioButton
        GRFICADETIEMPOLabel    matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end


    methods (Access = private)

        function [xnHA,ynHA,mHA,xRHA] = AleatoriedadAbdHombro (app,noise)

            %nueva x
            xHA = 0:0.1:1;
            xnHA = xHA+ 0,01*rand(1,11);
            xnHA(1) = 0;
            xnHA(end)=1;

            xRHA = 0:0.001:1;

            %nueva y
            yHA = [10,14,19,26,31,33,30,26,21,16,10];
            ynHA = yHA + 5*rand(1,11);
            ynHA(1) = yHA(1);
            ynHA(end) = yHA(end);

            %----------------------------
            maxim= 4;
            minim= 1;% el rango pongo el que vea apropiado
            duration = minim+(maxim-minim)*rand(1);

            ppHA= makima(xnHA*duration,ynHA);

            mHA= ppval(ppHA, xRHA*duration);


            if noise == 1

                mHA = mHA + 3*rand(1,1001);

                %Filtrado
                xHAFiltrar = xRHA*duration;
                yHAFiltrar = mHA;
                [Asgolay,window] = smoothdata(yHAFiltrar,'sgolay');
                plot(app.UIAxes,xHAFiltrar,Asgolay,'r'), hold (app.UIAxes)


                %Guardar variables en el workspace
                %assignin('base','nombre wokspace',variable)
                assignin('base','xHAFiltrar',xHAFiltrar)
                assignin('base','Asgolay',Asgolay)
                x = xHAFiltrar';
                y = Asgolay';

                %Escribir en un excel
                T = table (x, y);
                assignin('base','T',T)

                L = {'Variable x','Variable y'};
                C = table2cell(T);
                assignin('base','L',L)
                assignin('base','C',C)

                %Concateno en la matriz cell la legenda de cada columna
                A = [L;C];
                assignin('base','A',A)

                %crea y reescribe
                xlswrite('datosHA.xlsx',A,'xlswrite','A2');


            end
        end

        function [xnCS,ynCS,mCS,xRCS] =  AleatoriedadSCodo(app,noise)

            %Creacion grafica
            xCS = 0:0.1:1;
            xnCS = xCS+ 0,01*rand(1,11);
            xnCS(1) = 0;
            xnCS(end)=1;
            xRCS = 0:0.001:1;
            yCS = [25,28,35,42,52,51,54,25,17,10,25];
            ynCS = yCS + 5*rand(1,11);
            ynCS(1) = yCS(1);
            ynCS(end) = yCS(end);
            maxim= 1.10;
            minim= 0.8;
            duration = minim+(maxim-minim)*rand(1);
            ppCS= makima(xnCS*duration,ynCS);
            mCS= ppval(ppCS, xRCS*duration);


            if noise == 1
                mCS = mCS + 3*rand(1,1001);
                %Filtrado
                xCSFiltrar = xRCS*duration;
                yCSFiltrar = mCS;
                [Asgolay,window] = smoothdata(yCSFiltrar,'sgolay');
                plot(app.UIAxes,xCSFiltrar,Asgolay,'r'), hold (app.UIAxes)

                %Guardar variables en el workspace
                %assignin('base','nombre wokspace',variable)
                assignin('base','xCSFiltrar',xCSFiltrar)
                assignin('base','Asgolay',Asgolay)
                x = xCSFiltrar';
                y = Asgolay';

                %Escribir en un excel
                T = table (x, y);
                assignin('base','T',T)

                L = {'Variable x','Variable y'};
                C = table2cell(T);
                assignin('base','L',L)
                assignin('base','C',C)

                %Concateno en la matriz cell la legenda de cada columna
                A = [L;C];
                assignin('base','A',A)

                %crea y reescribe
                xlswrite('datosCS.xlsx',A,'xlswrite','A2');

            end
        end


        function [xnCF,ynCF,mCF,xRCF] = AleatoriedadFCodo(app,noise)
            %Creacion grafica
            xCF = 0:0.1:1;
            xnCF = xCF+ 0,01*rand(1,11);
            xnCF(1) = 0;
            xnCF(end)=1;
            xRCF = 0:0.001:1;
            yCF = [67,70,89,108,115,120,115,107,83,72,67];
            ynCF = yCF + 5*rand(1,11);
            ynCF(1) = yCF(1);
            ynCF(end) = yCF(end);
            maxim= 1.10;
            minim= 0.8;
            duration = minim+(maxim-minim)*rand(1);
            ppCF= makima(xnCF*duration,ynCF);
            mCF= ppval(ppCF, xRCF*duration);

            if noise == 1
                mCF = mCF + 3*rand(1,1001);
                %Filtrado
                xCFFiltrar = xRCF*duration;
                yCFFiltrar = mCF;
                [Asgolay,window] = smoothdata(yCFFiltrar,'sgolay');
                plot(app.UIAxes,xCFFiltrar,Asgolay,'r'), hold (app.UIAxes)

                %Guardar variables en el workspace
                %assignin('base','nombre wokspace',variable)
                assignin('base','xCFFiltrar',xCFFiltrar)
                assignin('base','Asgolay',Asgolay)
                x = xCFFiltrar';
                y = Asgolay';

                %Escribir en un excel
                T = table (x, y);
                assignin('base','T',T)

                L = {'Variable x','Variable y'};
                C = table2cell(T);
                assignin('base','L',L)
                assignin('base','C',C)

                %Concateno en la matriz cell la legenda de cada columna
                A = [L;C];
                assignin('base','A',A)

                %crea y reescribe
                xlswrite('datosCF.xlsx',A,'xlswrite','A2');

            end
        end
        function [xnMF,ynMF,mMF,xRMF] = AleatoriedadFMuneca(app,noise)
            %Creacion grafica
            xMF = 0:0.1:1;
            xnMF = xMF+ 0,01*rand(1,11);
            xnMF(1) = 0;
            xnMF(end)=1;
            xRMF = 0:0.001:1;
            yMF = [27,25,23,20,21,20,22,20,23,25,27];
            ynMF = yMF + 5*rand(1,11);
            ynMF(1) = yMF(1);
            ynMF(end) = yMF(end);
            maxim= 1.10;
            minim= 0.8;%el rango pongo el que vea apropiado
            duration = minim+(maxim-minim)*rand(1);
            ppMF= makima(xnMF*duration,ynMF);
            mMF= ppval(ppMF, xRMF*duration);

            if noise == 1
                mMF = mMF + 3*rand(1,1001);
                %Filtrado
                xMFFiltrar = xRMF*duration;
                yMFFiltrar = mMF;
                [Asgolay,window] = smoothdata(yMFFiltrar,'sgolay');
                plot(app.UIAxes,xMFFiltrar,Asgolay,'r'), hold (app.UIAxes)


                %Guardar variables en el workspace
                %assignin('base','nombre wokspace',variable)
                assignin('base','xMFFiltrar',xMFFiltrar)
                assignin('base','Asgolay',Asgolay)
                x = xMFFiltrar';
                y = Asgolay';

                %Escribir en un excel
                T = table (x, y);
                assignin('base','T',T)

                L = {'Variable x','Variable y'};
                C = table2cell(T);
                assignin('base','L',L)
                assignin('base','C',C)

                %Concateno en la matriz cell la legenda de cada columna
                A = [L;C];
                assignin('base','A',A)

                %crea y reescribe
                xlswrite('datosMF.xlsx',A,'xlswrite','A2');

            end
        end
        
       function [xnMA,ynMA,mMA,xRMA] = AleatoriedadAbdMuneca(app,noise)
%Creacion grafica
xMA = 0:0.1:1;
xnMA = xMA+ 0,01*rand(1,11); 
xnMA(1) = 0;
xnMA(end)=1;
xRMA = 0:0.001:1;
yMA = [5,6,10,12,15,16,15,12,9,6,5];
ynMA = yMA + 5*rand(1,11);
ynMA(1) = yMA(1);
ynMA(end) = yMA(end);
maxim= 1.10;
minim= 0.8;
duration = minim+(maxim-minim)*rand(1);
ppMA= makima(xnMA*duration,ynMA);
mMA= ppval(ppMA, xRMA*duration);

if noise == 1
     mMA = mMA + 3*rand(1,1001);
    %Filtrado 
xMAFiltrar = xRMA*duration;
yMAFiltrar = mMA;
[Asgolay,window] = smoothdata(yMAFiltrar,'sgolay');
plot(app.UIAxes,xMAFiltrar,Asgolay,'r'), hold (app.UIAxes)

%Guardar variables en el workspace 
%assignin('base','nombre wokspace',variable)
assignin('base','xMAFiltrar',xMAFiltrar)
assignin('base','Asgolay',Asgolay)
x = xMAFiltrar';
y = Asgolay';

%Escribir en un excel 
T = table (x, y);
assignin('base','T',T)

L = {'Variable x','Variable y'};
C = table2cell(T);
assignin('base','L',L)
assignin('base','C',C)

%Concateno en la matriz cell la legenda de cada columna 
A = [L;C];
assignin('base','A',A)

%crea y reescribe
xlswrite('datosMA.xlsx',A,'xlswrite','A2');
end    
        end
        
       function [xnHF,ynHF,mHF,xRHF] = AleatoriedadFHombro (app, noise)
%eje x de la señal
%xvar = [x0, x1] que indica el valor maximo y minimo del final del eje x
%y = señal
%yvar = [y0, y1] factor de multiplicacion maximo y minimo
%duration= el intervalo de tiempo que va a durar
%noise = si aplicar rido o no


%nueva x
xHF = 0:0.1:1;
xnHF = xHF+ 0,01*rand(1,11); 
xnHF(1) = 0;
xnHF(end)=1;

xRHF = 0:0.001:1;

%nueva y
yHF = [40,42,45,50,56,60,56,50,45,42,40];
ynHF = yHF + 3*rand(1,11);
ynHF(1) = yHF(1);
ynHF(end) = yHF(end);

%----------------------------
maxim= 1.10;
minim= 0.8;%el rango pongo el que vea apropiado
duration = minim+(maxim-minim)*rand(1);

ppHF= makima(xnHF*duration,ynHF);

mHF= ppval(ppHF, xRHF*duration);


%Filtrado 
if noise == 1
      mHF = mHF + 3*rand(1,1001);
    
xHFFiltrar = xRHF*duration;
yHFFiltrar = mHF;
[Asgolay,window] = smoothdata(yHFFiltrar,'sgolay');
plot(app.UIAxes,xHFFiltrar,Asgolay,'r'),hold (app.UIAxes)
%Guardar variables en el workspace 
%assignin('base','nombre wokspace',variable)
assignin('base','xHFFiltrar',xHFFiltrar)
assignin('base','Asgolay',Asgolay)
x = xHFFiltrar';
y = Asgolay';

%Escribir en un excel 
T = table (x, y);
assignin('base','T',T)

L = {'Variable x','Variable y'};
C = table2cell(T);
assignin('base','L',L)
assignin('base','C',C)

%Concateno en la matriz cell la legenda de cada columna 
A = [L;C];
assignin('base','A',A)

%crea y reescribe
xlswrite('datosHF.xlsx',A,'xlswrite','A2');

end


        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MovimientoButtonGroup.Visible = 'Off';
        end

        % Callback function
        function VolverButtonPushed(app, event)
            metricas;
            closereq();
        end

        % Selection changed function: MovimientoButtonGroup
        function MovimientoButtonGroupSelectionChanged(app, event)
          selectedButton = app.MovimientoButtonGroup.SelectedObject;
            V1 = app.AbduccionHombroButton.Value;
            V2 = app.FlexionHombroButton.Value;
            V3 = app.FlexionCodoButton.Value;
            V4 = app.SupinacionCodoButton.Value;
            V5 = app.FlexionMunecaButton.Value;
            V6 = app.AbduccionMuecaButton.Value;


            if V1 == 1
                app.UIAxes.Title.String = 'Abducción del Hombro';
                [num,txt,raw]= xlsread('datosHA.xlsx','xlswrite');
                plot(app.UIAxes,num(:,1),num(:,2))
            end
            if V2 == 1
                app.UIAxes.Title.String = 'Flexión del Hombro';
                [num,txt,raw]= xlsread('datosHF.xlsx','xlswrite');
                plot(app.UIAxes,num(:,1),num(:,2))
            end
            if V3 == 1
                app.UIAxes.Title.String = 'Flexión del Codo';
                [num,txt,raw]= xlsread('datosCF.xlsx','xlswrite');
                plot(app.UIAxes,num(:,1),num(:,2))
            end
            if V4 == 1
                app.UIAxes.Title.String = 'Supinación del Codo';
                [num,txt,raw]= xlsread('datosCS.xlsx','xlswrite');
                plot(app.UIAxes,num(:,1),num(:,2))
            end
            if V5 == 1
                app.UIAxes.Title.String = 'Flexión de la Muñeca';
                [num,txt,raw]= xlsread('datosMF.xlsx','xlswrite');
                plot(app.UIAxes,num(:,1),num(:,2))
            end
            if V6 == 1
                app.UIAxes.Title.String = 'Abducción de la Muñeca';
                [num,txt,raw]= xlsread('datosMA.xlsx','xlswrite');
                plot(app.UIAxes,num(:,1),num(:,2))
            end
        end

        % Value changed function: MostrarsubdivisindemovimientosCheckBox
        function MostrarsubdivisindemovimientosCheckBoxValueChanged(app, event)
            value = app.MostrarsubdivisindemovimientosCheckBox.Value;
            if value == 1
                app.MovimientoButtonGroup.Visible = 'On';
            else
                app.MovimientoButtonGroup.Visible = 'Off';
            end

        end

        % Button pushed function: VolverajugarButton
        function VolverajugarButtonPushed(app, event)
           GraficasTiempo;
           closereq();

            %{
            % ABDUCCION HOMBRO
            xHA = 0:0.1:1;
            xnHA = xHA+ 0,01*rand(1,11);
            xnHA(1) = 0;
            xnHA(end)=1;
            xRHA = 0:0.001:1;
            yHA = [10,14,19,26,31,33,30,26,21,16,10];
            ynHA = yHA + 1.65*rand(1,11);
            ynHA(1) = yHA(1);
            ynHA(end) = yHA(end);
            maxim= 1.10;
            minim= 0.8; % el rango pongo el que vea apropiado
            duration = minim+(maxim-minim)*rand(1);
            ppHA= makima(xnHA*duration,ynHA);
            mHA= ppval(ppHA, xRHA*duration);


            xxHA = xRHA*duration;
            yyHA = mHA;

            i = 1;
            while i<length(xxHA)
                plot(app.UIAxes,xxHA(1:i),yyHA(1:i))
                i = i+4;
                pause(0.01)
            end
    

            %FLEXION HOMBRO
            xHF = 0:0.1:1;
            xnHF = xHF+ 0,01*rand(1,11);
            xnHF(1) = 0;
            xnHF(end)=1;
            xRHF = 0:0.001:1;
            yHF = [40,42,45,50,56,60,56,50,45,42,40];
            ynHF = yHF + 5*rand(1,11);
            ynHF(1) = yHF(1);
            ynHF(end) = yHF(end);
            maxim= 1.10;
            minim= 0.8;
            duration = minim+(maxim-minim)*rand(1);
            ppHF= makima(xnHF*duration,ynHF);
            mHF= ppval(ppHF, xRHF*duration);

            xxHF = xRHF*duration;
            yyHF = mHF;

            i = 1;
            while i<length(xxHF)
                plot(app.UIAxes,xxHF(1:i),yyHF(1:i))
                i = i+4;
                pause(0.01)
            end
          
            %FLEXION CODO
            xCF = 0:0.1:1;
            xnCF = xCF+ 0,01*rand(1,11);
            xnCF(1) = 0;
            xnCF(end)=1;
            xRCF = 0:0.001:1;
            yCF = [67,70,89,108,115,120,115,107,83,72,67];
            ynCF = yCF + 5*rand(1,11);
            ynCF(1) = yCF(1);
            ynCF(end) = yCF(end);
            maxim= 1.10;
            minim= 0.8;
            duration = minim+(maxim-minim)*rand(1);
            ppCF= makima(xnCF*duration,ynCF);
            mCF= ppval(ppCF, xRCF*duration);

            xxCF = xRCF*duration;
            yyCF = mCF;

            i = 1;
            while i<length(xxCF)
                plot(app.UIAxes,xxCF(1:i),yyCF(1:i))
                i = i+4;
                pause(0.01)
            end

            %SUPINACION CODO
            xCS = 0:0.1:1;
            xnCS = xCS+ 0,01*rand(1,11);
            xnCS(1) = 0;
            xnCS(end)=1;
            xRCS = 0:0.001:1;
            yCS = [25,28,35,42,52,51,54,25,17,10,25];
            ynCS = yCS + 5*rand(1,11);
            ynCS(1) = yCS(1);
            ynCS(end) = yCS(end);
            maxim= 1.10;
            minim= 0.8;%el rango pongo el que vea apropiado
            duration = minim+(maxim-minim)*rand(1);
            ppCS= makima(xnCS*duration,ynCS);
            mCS= ppval(ppCS, xRCS*duration);

            xxCS = xRCS*duration;
            yyCS = mCS;

            i = 1;
            while i<length(xxCS)
                plot(app.UIAxes,xxCS(1:i),yyCS(1:i))
                i = i+4;
                pause(0.01)
            end
           
            %FLEXIÓN MUÑECA
            xMF = 0:0.1:1;
            xnMF = xMF+ 0,01*rand(1,11);
            xnMF(1) = 0;
            xnMF(end)=1;
            xRMF = 0:0.001:1;
            yMF = [27,25,23,20,21,20,22,20,23,25,27];
            ynMF = yMF + 5*rand(1,11);
            ynMF(1) = yMF(1);
            ynMF(end) = yMF(end);
            maxim= 1.10;
            minim= 0.8;%el rango pongo el que vea apropiado
            duration = minim+(maxim-minim)*rand(1);
            ppMF= makima(xnMF*duration,ynMF);
            mMF= ppval(ppMF, xRMF*duration);

            xxMF = xRMF*duration;
            yyMF = mMF;

            i = 1;
            while i<length(xxMF)
                plot(app.UIAxes,xxMF(1:i),yyMF(1:i))
                i = i+4;
                pause(0.01)
            end
        

            %ABDUCCION MUÑECA
            xMA = 0:0.1:1;
            xnMA = xMA+ 0,01*rand(1,11);
            xnMA(1) = 0;
            xnMA(end)=1;
            xRMA = 0:0.001:1;
            yMA = [5,6,10,12,15,16,15,12,9,6,5];
            ynMA = yMF + 5*rand(1,11);
            ynMA(1) = yMA(1);
            ynMA(end) = yMA(end);
            maxim= 1.10;
            minim= 0.8;%el rango pongo el que vea apropiado
            duration = minim+(maxim-minim)*rand(1);
            ppMA= makima(xnMA*duration,ynMA);
            mMA= ppval(ppMA, xRMA*duration);
            xxMA = xRMA*duration;
            yyMA = mMA;

            i = 1;
            while i<length(xxMA)
                plot(app.UIAxes,xxMA(1:i),yyMA(1:i))
                i = i+4;
                pause(0.01)
            end
            %}

            %{
longitudmaxima = length(xxHA);
if longitudmaxima <  length(xxHF)
longitudmaxima =  length(xxHF);
end
if longitudmaxima <  length(xxCS)
longitudmaxima = length(xxCS);
end
if longitudmaxima <  length(xxCF)
longitudmaxima = length(xxCF);
end
if longitudmaxima <  length(xxMF)
longitudmaxima = length(xxMF);
end
if longitudmaxima <  length(xxMA)
longitudmaxima = length(xxMA);
end

i = 1;
while i<length(longitudmaxima) 
plot(app.UIAxes,xxMA(1:i),yyMA(1:i),'r'), hold on, 
plot(app.UIAxes,xxMF(1:i),yyMF(1:i),'g'), hold on,
plot(app.UIAxes,xxCF(1:i),yyCF(1:i),'c'), hold on,
plot(app.UIAxes,xxCS(1:i),yyCS(1:i),'m'), hold on, 
plot(app.UIAxes,xxHF(1:i),yyHF(1:i),'y'), hold on, 
plot(app.UIAxes,xxHA(1:i),yyHA(1:i))

i = i+4;
pause(0.01)
end
            %}


        end

        % Button pushed function: EscogerjuegoButton
        function EscogerjuegoButtonPushed(app, event)
            metricas;
            closereq();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GRFICADETIEMPOLabel = uilabel(app.GridLayout);
            app.GRFICADETIEMPOLabel.Text = 'GRÁFICA DE TIEMPO';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.MovimientoButtonGroup = uibuttongroup(app.GridLayout2);
            app.MovimientoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MovimientoButtonGroupSelectionChanged, true);
            app.MovimientoButtonGroup.Title = 'Movimiento ';
            app.FlexionHombroButton = uiradiobutton(app.MovimientoButtonGroup);
            app.FlexionHombroButton.Text = 'Flexión Hombro';
            app.FlexionHombroButton.Value = true;
            app.AbduccionHombroButton = uiradiobutton(app.MovimientoButtonGroup);
            app.AbduccionHombroButton.Text = 'Abducción Hombro ';
            app.FlexionCodoButton = uiradiobutton(app.MovimientoButtonGroup);
            app.FlexionCodoButton.Text = 'Flexión Codo';
            app.SupinacionCodoButton = uiradiobutton(app.MovimientoButtonGroup);
            app.SupinacionCodoButton.Text = 'Supinación Codo';
            app.FlexionMunecaButton = uiradiobutton(app.MovimientoButtonGroup);
            app.FlexionMunecaButton.Text = 'Flexión Muñeca';
            app.AbduccionMuecaButton = uiradiobutton(app.MovimientoButtonGroup);
            app.AbduccionMuecaButton.Text = 'Abducción muñeca';
            app.MostrarsubdivisindemovimientosCheckBox = uicheckbox(app.GridLayout2);
            app.MostrarsubdivisindemovimientosCheckBox.ValueChangedFcn = createCallbackFcn(app, @MostrarsubdivisindemovimientosCheckBoxValueChanged, true);
            app.MostrarsubdivisindemovimientosCheckBox.Text = 'Mostrar subdivisión de movimientos ';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.EscogerjuegoButton = uibutton(app.GridLayout3, 'push');
            app.EscogerjuegoButton.ButtonPushedFcn = createCallbackFcn(app, @EscogerjuegoButtonPushed, true);
            app.EscogerjuegoButton.Text = {'Escoger juego'; ''; ''};
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.VolverajugarButton = uibutton(app.GridLayout4, 'push');
            app.VolverajugarButton.ButtonPushedFcn = createCallbackFcn(app, @VolverajugarButtonPushed, true);
            app.VolverajugarButton.Text = {'Volver a jugar'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tiempo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end