classdef AvatarPatologico < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        InicioButton               matlab.ui.control.Button
        GridLayout2                matlab.ui.container.GridLayout
        FinalizarmovimientoButton  matlab.ui.control.Button
        IniciarmovimientoButton    matlab.ui.control.Button
        MetricaButtonGroup         matlab.ui.container.ButtonGroup
        CalidadButton              matlab.ui.control.RadioButton
        CantidadButton             matlab.ui.control.RadioButton
        TiempoButton               matlab.ui.control.RadioButton
        UIAxes                     matlab.ui.control.UIAxes
    end

    
   properties (Access = private)
        pintar;
        
       
        numdatosCF ;
        numdatosCS ;
        numdatosHA ;
        numdatosHF ;
        numdatosMA ;
        numdatosMF ;
    end
    methods (Access = public)

        function crearAvatar(app,YPRhombro,YPRcodo,YPRwrist)
           
            f = [1 2 3 4; 5 6 7 8;1 2 6 5;2 3 7 6;3 4 8 7;4 1 5 8];
            color = [1 0 0;0 1 0;0 0 1;1 1 0;1 0 1;1 1 1];
            torso = [30,-10,10;30,40,10;90,40,10;90,-10,10;30,-10,120;30,40,120;90,40,120;90,-10,120];
            brazo = [10,0,60;10,30,60;30,30,60;30,0,60;10,0,120;10,30,120;30,30,120;30,0,120];
            antebrazo = [12,5,10;12,25,10;28,25,10;28,5,10;12,5,60;12,25,60;28,25,60;28,5,60];
            mano = [10,10,-10;10,20,-10;30,20,-10;30,10,-10;10,10,10;10,20,10;30,20,10;30,10,10];
            cuello = [50,0,120;50,30,120;70,30,120;70,0,120;50,0,140;50,30,140;70,30,140;70,0,140];
            cabeza = [30,-10,140;30,40,140;90,40,140;90,-10,140;30,-10,180;30,40,180;90,40,180;90,-10,180];


            Rhombro = rotationMatrix(YPRhombro(1),YPRhombro(2),YPRhombro(3));
            Rcodo = rotationMatrix(YPRcodo(1),YPRcodo(2),YPRcodo(3));
            Rwrist = rotationMatrix(YPRwrist(1),YPRwrist(2),YPRwrist(3));

            %

            manoRotada = applyRotationNx3(applyRotationNx3(applyRotationNx3(mano,...
                sum(antebrazo(1:4,:))/4,Rwrist),sum(brazo(1:4,:))/4,Rcodo),(brazo(7,:)+brazo(8,:))/2,Rhombro);

            %LOS DOS PUNTOS SIGNIFICAN, TODOSS LOS PUNTOS EN LA DIMENSION
            antebrazoRotado = applyRotationNx3(applyRotationNx3(antebrazo,...
                sum(brazo(1:4,:))/4,Rcodo),(brazo(7,:)+brazo(8,:))/2,Rhombro);
            brazoRotado = applyRotationNx3(brazo,...
                (brazo(7,:)+brazo(8,:))/2,Rhombro);

            %LA ROTACION DEL TORSO LA TENGO QUE APLICAR A TODOS MIS COMPONENES
            %EL APPLYROTATION CONSTA DE : MODEL, MATRIZ DE ROTACION Y CENTRO DE ROTACION
            %CENTRO DE ROTACION : SUMO LOS PUNTOS / CANTIDAD DE PUNTOS
            %ENCADENO LOS CENTROS DE ROTACION --> CADENA CINEMATICA

            patch(app.UIAxes,'faces',f,'vertices',brazoRotado,'FaceVertexCData',color,'FaceColor','flat')
            patch(app.UIAxes,'faces',f,'vertices',antebrazoRotado,'FaceVertexCData',color,'FaceColor','flat')
            patch(app.UIAxes,'faces',f,'vertices',manoRotada,'FaceVertexCData',color,'FaceColor','flat')
            patch(app.UIAxes,'faces',f,'vertices',torso,'FaceVertexCData',color,'FaceColor','flat')
            patch(app.UIAxes,'faces',f,'vertices',cuello,'FaceVertexCData',color,'FaceColor','flat')
            patch(app.UIAxes,'faces',f,'vertices',cabeza,'FaceVertexCData',color,'FaceColor','flat')

            view(app.UIAxes,[-5,2])


        end
    end

    methods (Access = public)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

 crearAvatar(app,[0,10,-40],[25,0,-67],[0,5,-27])
 app.UIAxes.Title.String = 'Metrica Calidad';
                    %Leer datos Calidad
                    [num ,x] = LeerExcel(app,'datosCFCl')
                    app.numdatosCF = num ;
                    [num ,x] = LeerExcel(app,'datosCSCl')
                    app.numdatosCS = num ;
                    [num ,x] = LeerExcel(app,'datosHACl')
                    app.numdatosHA = num ;
                    [num ,x] = LeerExcel(app,'datosHFCl')
                    app.numdatosHF = num ;
                    [num ,x] = LeerExcel(app,'datosMACl')
                    app.numdatosMA = num ;
                    [num ,x] = LeerExcel(app,'datosMFCl') 
                    app.numdatosMF = num ;
                    
                    i = 1;
                    app.pintar=1;
                    while (app.pintar)
                   
                    crearAvatar(app,[0,app.numdatosHA(i,2),-app.numdatosHF(i,2)],[app.numdatosCS(i,2),0, - app.numdatosCF(i,2)],[0,app.numdatosMA(i,2),-app.numdatosMF(i,2)]) 
                    pause(0.3);
                    delete(findobj(app.UIAxes,'type', 'patch'));
                    
                        if (i >= 1001)
                        crearAvatar(app,[0,10,-40],[25,0,-67],[0,5,-27]);
                        pause(0.5);
                        break;
                        end
               
                        if (app.pintar == 0)
                            break;
                        end
                    i = i+100;
                    end
        end

        % Button pushed function: IniciarmovimientoButton
        function IniciarmovimientoButtonPushed(app, event)
          
                    i = 1;
                    app.pintar=1;
                    while (app.pintar)
                   
                    crearAvatar(app,[0,app.numdatosHA(i,2),-app.numdatosHF(i,2)],[app.numdatosCS(i,2),0, - app.numdatosCF(i,2)],[0,app.numdatosMA(i,2),-app.numdatosMF(i,2)])
                    pause(0.3);
                    delete(findobj(app.UIAxes,'type', 'patch'));
                    
                        if (i >= 1001)
                        crearAvatar(app,[0,10,-40],[25,0,-67],[0,5,-27]);
                        pause(0.5);
                        break;
                        end
               
                        if (app.pintar == 0)
                            break;
                        end
                    i = i+100;
                    end
           
            
        end

        % Button pushed function: FinalizarmovimientoButton
        function FinalizarmovimientoButtonPushed(app, event)
            app.pintar = 0;
        end

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)
            Inicio;
            closereq ();
        end

        % Selection changed function: MetricaButtonGroup
        function MetricaButtonGroupSelectionChanged(app, event)
        selectedButton = app.MetricaButtonGroup.SelectedObject;
            V1 = app.TiempoButton.Value;
            V2 = app.CantidadButton.Value;
            V3 = app.CalidadButton.Value;
            if V1 == 1
                app.UIAxes.Title.String = 'Metrica tiempo';
                    %Leer datos Tiempo
                    [num ,x] = LeerExcel(app,'datosCF')
                    app.numdatosCF = num ;
                    [num ,x] = LeerExcel(app,'datosCS')
                    app.numdatosCS = num ;
                    [num ,x] = LeerExcel(app,'datosHA')
                    app.numdatosHA = num ;
                    [num ,x] = LeerExcel(app,'datosHF')
                    app.numdatosHF = num ;
                    [num ,x] = LeerExcel(app,'datosMA')
                    app.numdatosMA = num ;
                    [num ,x] = LeerExcel(app,'datosMF')
                    app.numdatosMF = num ;
                    
                    i = 1;
                    app.pintar=1;
                    while (app.pintar)
                   
                    crearAvatar(app,[0,app.numdatosHA(i,2),-app.numdatosHF(i,2)],[app.numdatosCS(i,2),0, - app.numdatosCF(i,2)],[0,app.numdatosMA(i,2),-app.numdatosMF(i,2)])
                    pause(0.3);
                    delete(findobj(app.UIAxes,'type', 'patch'));
                    
                        if (i >= 1001)
                        crearAvatar(app,[0,10,-40],[25,0,-67],[0,5,-27]);
                        pause(0.5);
                        break;
                        end
               
                        if (app.pintar == 0)
                            break;
                        end
                    i = i+100;
                    end
           
            end
            
            if V2 == 1
                app.UIAxes.Title.String = 'Metrica Cantidad';
                    %Leer datos Cantidad
                    [num ,x] = LeerExcel(app,'datosCFC')
                    app.numdatosCF = num ; 
                    [num ,x] = LeerExcel(app,'datosCSC')
                    app.numdatosCS = num;
                    [num ,x] =  LeerExcel(app,'datosHAC')
                    app.numdatosHA = num;
                    [num ,x] = LeerExcel(app,'datosHFC')
                    app.numdatosHF = num;
                    [num ,x] = LeerExcel(app,'datosMAC')
                    app.numdatosMA = num;
                    [num ,x] = LeerExcel(app,'datosMFC')
                    app.numdatosMF = num;
                    
                    i = 1;
                    app.pintar=1;
                    while (app.pintar)
                   
                    crearAvatar(app,[0,app.numdatosHA(i,2),-app.numdatosHF(i,2)],[app.numdatosCS(i,2),0, - app.numdatosCF(i,2)],[0,app.numdatosMA(i,2),-app.numdatosMF(i,2)]) 
                    pause(0.3);
                    delete(findobj(app.UIAxes,'type', 'patch'));
                    
                        if (i >= 1001)
                        crearAvatar(app,[0,10,-40],[25,0,-67],[0,5,-27]);
                        pause(0.5);
                        break;
                        end
               
                        if (app.pintar == 0)
                            break;
                        end
                    i = i+100;
                    end
                
            end
            
            if V3 == 1
                app.UIAxes.Title.String = 'Metrica Calidad';
                    %Leer datos Calidad
                    [num ,x] = LeerExcel(app,'datosCFCl')
                    app.numdatosCF = num ;
                    [num ,x] = LeerExcel(app,'datosCSCl')
                    app.numdatosCS = num ;
                    [num ,x] = LeerExcel(app,'datosHACl')
                    app.numdatosHA = num ;
                    [num ,x] = LeerExcel(app,'datosHFCl')
                    app.numdatosHF = num ;
                    [num ,x] = LeerExcel(app,'datosMACl')
                    app.numdatosMA = num ;
                    [num ,x] = LeerExcel(app,'datosMFCl') 
                    app.numdatosMF = num ;
                    
                    i = 1;
                    app.pintar=1;
                    while (app.pintar)
                   
                    crearAvatar(app,[0,app.numdatosHA(i,2),-app.numdatosHF(i,2)],[app.numdatosCS(i,2),0, - app.numdatosCF(i,2)],[0,app.numdatosMA(i,2),-app.numdatosMF(i,2)]) 
                    pause(0.3);
                    delete(findobj(app.UIAxes,'type', 'patch'));
                    
                        if (i >= 1001)
                        crearAvatar(app,[0,10,-40],[25,0,-67],[0,5,-27]);
                        pause(0.5);
                        break;
                        end
               
                        if (app.pintar == 0)
                            break;
                        end
                    i = i+100;
                    end
                    
                
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.InicioButton = uibutton(app.GridLayout, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Text = 'Inicio';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.FinalizarmovimientoButton = uibutton(app.GridLayout2, 'push');
            app.FinalizarmovimientoButton.ButtonPushedFcn = createCallbackFcn(app, @FinalizarmovimientoButtonPushed, true);
            app.FinalizarmovimientoButton.Text = {'Finalizar movimiento'; ''};
            app.IniciarmovimientoButton = uibutton(app.GridLayout2, 'push');
            app.IniciarmovimientoButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarmovimientoButtonPushed, true);
            app.IniciarmovimientoButton.Text = 'Iniciar movimiento';
            app.MetricaButtonGroup = uibuttongroup(app.GridLayout);
            app.MetricaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MetricaButtonGroupSelectionChanged, true);
            app.MetricaButtonGroup.Title = 'Metrica';
            app.CalidadButton = uiradiobutton(app.MetricaButtonGroup);
            app.CalidadButton.Text = 'Calidad';
            app.CalidadButton.Value = true;
            app.CantidadButton = uiradiobutton(app.MetricaButtonGroup);
            app.CantidadButton.Text = 'Cantidad';
            app.TiempoButton = uiradiobutton(app.MetricaButtonGroup);
            app.TiempoButton.Text = 'Tiempo';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, {'Avatar'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-10 100];
            app.UIAxes.YLim = [-130 80];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AvatarPatologico

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end