classdef Calidad < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        GRFICADECALIDADLabel   matlab.ui.control.Label
        GridLayout2            matlab.ui.container.GridLayout
        MovimientoButtonGroup  matlab.ui.container.ButtonGroup
        FlexionHombroButton    matlab.ui.control.RadioButton
        AbduccionHombroButton  matlab.ui.control.RadioButton
        FlexionCodoButton      matlab.ui.control.RadioButton
        SupinacionCodoButton   matlab.ui.control.RadioButton
        FlexionMunecaButton    matlab.ui.control.RadioButton
        AbduccionMuecaButton   matlab.ui.control.RadioButton
        MostrarsubdivisindemovimientosCheckBox  matlab.ui.control.CheckBox
        GridLayout3            matlab.ui.container.GridLayout
        EscogerjuegoButton     matlab.ui.control.Button
        GridLayout4            matlab.ui.container.GridLayout
        VolverajugarButton     matlab.ui.control.Button
        InicioButton           matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end


    methods (Access = private)

        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MovimientoButtonGroup.Visible = 'Off';
        end

        % Selection changed function: MovimientoButtonGroup
        function MovimientoButtonGroupSelectionChanged(app, event)
          selectedButton = app.MovimientoButtonGroup.SelectedObject;
            V1 = app.AbduccionHombroButton.Value;
            V2 = app.FlexionHombroButton.Value;
            V3 = app.FlexionCodoButton.Value;
            V4 = app.SupinacionCodoButton.Value;
            V5 = app.FlexionMunecaButton.Value;
            V6 = app.AbduccionMuecaButton.Value;


            if V1 == 1
                app.UIAxes.Title.String = 'Abducción del Hombro';
                [num,txt,raw]= xlsread('datosHACl.xlsx','xlswrite');
                plot(app.UIAxes,num(:,1),num(:,2))
            end
            if V2 == 1
                app.UIAxes.Title.String = 'Flexión del Hombro';
                [num,txt,raw]= xlsread('datosHFCl.xlsx','xlswrite');
                plot(app.UIAxes,num(:,1),num(:,2))
            end
            if V3 == 1
                app.UIAxes.Title.String = 'Flexión del Codo';
                [num,txt,raw]= xlsread('datosCFCl.xlsx','xlswrite');
                plot(app.UIAxes,num(:,1),num(:,2))
            end
            if V4 == 1
                app.UIAxes.Title.String = 'Supinación del Codo';
                [num,txt,raw]= xlsread('datosCSCl.xlsx','xlswrite');
                plot(app.UIAxes,num(:,1),num(:,2))
            end
            if V5 == 1
                app.UIAxes.Title.String = 'Flexión de la Muñeca';
                [num,txt,raw]= xlsread('datosMFCl.xlsx','xlswrite');
                plot(app.UIAxes,num(:,1),num(:,2))
            end
            if V6 == 1
                app.UIAxes.Title.String = 'Abducción de la Muñeca';
                [num,txt,raw]= xlsread('datosMACl.xlsx','xlswrite');
                plot(app.UIAxes,num(:,1),num(:,2))
            end
        end

        % Value changed function: 
        % MostrarsubdivisindemovimientosCheckBox
        function MostrarsubdivisindemovimientosCheckBoxValueChanged(app, event)
            value = app.MostrarsubdivisindemovimientosCheckBox.Value;
            if value == 1
                app.MovimientoButtonGroup.Visible = 'On';
            else
                app.MovimientoButtonGroup.Visible = 'Off';
            end

        end

        % Button pushed function: VolverajugarButton
        function VolverajugarButtonPushed(app, event)
           GraficasCalidad;
           closereq();


        end

        % Button pushed function: EscogerjuegoButton
        function EscogerjuegoButtonPushed(app, event)
            metricas;
            closereq();
        end

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)
            Inicio;
             closereq();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GRFICADECALIDADLabel = uilabel(app.GridLayout);
            app.GRFICADECALIDADLabel.Text = 'GRÁFICA DE CALIDAD';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.MovimientoButtonGroup = uibuttongroup(app.GridLayout2);
            app.MovimientoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MovimientoButtonGroupSelectionChanged, true);
            app.MovimientoButtonGroup.Title = 'Movimiento ';
            app.FlexionHombroButton = uiradiobutton(app.MovimientoButtonGroup);
            app.FlexionHombroButton.Text = 'Flexión Hombro';
            app.FlexionHombroButton.Value = true;
            app.AbduccionHombroButton = uiradiobutton(app.MovimientoButtonGroup);
            app.AbduccionHombroButton.Text = 'Abducción Hombro ';
            app.FlexionCodoButton = uiradiobutton(app.MovimientoButtonGroup);
            app.FlexionCodoButton.Text = 'Flexión Codo';
            app.SupinacionCodoButton = uiradiobutton(app.MovimientoButtonGroup);
            app.SupinacionCodoButton.Text = 'Supinación Codo';
            app.FlexionMunecaButton = uiradiobutton(app.MovimientoButtonGroup);
            app.FlexionMunecaButton.Text = 'Flexión Muñeca';
            app.AbduccionMuecaButton = uiradiobutton(app.MovimientoButtonGroup);
            app.AbduccionMuecaButton.Text = 'Abducción muñeca';
            app.MostrarsubdivisindemovimientosCheckBox = uicheckbox(app.GridLayout2);
            app.MostrarsubdivisindemovimientosCheckBox.ValueChangedFcn = createCallbackFcn(app, @MostrarsubdivisindemovimientosCheckBoxValueChanged, true);
            app.MostrarsubdivisindemovimientosCheckBox.Text = 'Mostrar subdivisión de movimientos ';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.EscogerjuegoButton = uibutton(app.GridLayout3, 'push');
            app.EscogerjuegoButton.ButtonPushedFcn = createCallbackFcn(app, @EscogerjuegoButtonPushed, true);
            app.EscogerjuegoButton.Text = {'Escoger juego'; ''; ''};
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.VolverajugarButton = uibutton(app.GridLayout4, 'push');
            app.VolverajugarButton.ButtonPushedFcn = createCallbackFcn(app, @VolverajugarButtonPushed, true);
            app.VolverajugarButton.Text = {'Volver a jugar'; ''};
            app.InicioButton = uibutton(app.GridLayout4, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Text = {'Inicio'; ''; ''};
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calidad

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end