classdef Graficas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        GridLayout2       matlab.ui.container.GridLayout
        FHombro           matlab.ui.control.UIAxes
        FMuneca           matlab.ui.control.UIAxes
        AHombro           matlab.ui.control.UIAxes
        SCodo             matlab.ui.control.UIAxes
        AMuneca           matlab.ui.control.UIAxes
        FCodo             matlab.ui.control.UIAxes
        GridLayout3       matlab.ui.container.GridLayout
        InicioButton      matlab.ui.control.Button
        PatologiasButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%----------------------------------------GRAFICAS_IDEALES----------------------------------------------------------
%FLEXIÓN DE HOMBRO
xHF = 0:0.1:1;
yHF = [40,42,45,50,56,60,56,50,45,42,40];
xRHF = 0:0.001:1;
ppHF= makima(xHF,yHF);
mHF= ppval(ppHF, xRHF);
plot(app.FHombro,xRHF,mHF); 

%ABDUCCION DE HOMBRO
xHA = 0:0.1:1;
yHA = [10,14,19,26,31,33,30,26,21,16,10];
xRHA = 0:0.001:1;
ppHA= makima(xHA,yHA);
mHA= ppval(ppHA, xRHA);
plot(app.AHombro,xRHA,mHA,'r');

%FLEXIÓN DE CODO
xCF = 0:0.1:1;
yCF = [67,70,89,108,115,120,115,107,83,72,67];
xRCF = 0:0.001:1;
ppCF= makima(xCF,yCF);
mCF= ppval(ppCF, xRCF);
plot(app.FCodo,xRCF,mCF,'g');

%SUPINACION DE CODO
xCS= 0:0.1:1;
yCS = [25,28,35,42,52,51,54,25,17,10,25];
xRCS = 0:0.001:1;
ppCS= makima(xCS,yCS);
mCS= ppval(ppCS, xRCS);
plot(app.SCodo,xRCS,mCS);

%FLEXIÓN DE MUÑECA
xMF= 0:0.1:1;
yMF = [27,25,23,20,21,20,22,20,23,25,27];
xRMF = 0:0.001:1;
ppMF= makima(xMF,yMF);
mMF= ppval(ppMF, xRMF);
plot(app.FMuneca,xRMF,mMF);

%ABDUCCION DE MUÑECA
xMA = 0:0.1:1;
yMA = [5,6,10,12,15,16,15,12,9,6,5];
xRMA = 0:0.001:1;
ppMA= makima(xMA,yMA);
mMA= ppval(ppMA, xRMA);
plot(app.AMuneca,xRMA,mMA,'k');

        end

        % Callback function
        function JugarButtonPushed(app, event)
            metricas;
            closereq ();
        end

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)
            Inicio;
            closereq ();
        end

        % Button pushed function: PatologiasButton
        function PatologiasButtonPushed(app, event)
            AvatarPatologico;
            closereq ();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.FHombro = uiaxes(app.GridLayout2);
            title(app.FHombro, 'Flexion Hombro')
            xlabel(app.FHombro, 'Frames')
            ylabel(app.FHombro, 'Grados')
            zlabel(app.FHombro, 'Z')
            app.FMuneca = uiaxes(app.GridLayout2);
            title(app.FMuneca, 'Flexion Muñeca')
            xlabel(app.FMuneca, 'Frames')
            ylabel(app.FMuneca, 'Grados')
            zlabel(app.FMuneca, 'Z')
            app.AHombro = uiaxes(app.GridLayout2);
            title(app.AHombro, 'Abduccion Hombro')
            xlabel(app.AHombro, 'Frames')
            ylabel(app.AHombro, 'Grados')
            zlabel(app.AHombro, 'Z')
            app.SCodo = uiaxes(app.GridLayout2);
            title(app.SCodo, 'Supinacion Codo')
            xlabel(app.SCodo, 'Frames')
            ylabel(app.SCodo, 'Grados')
            zlabel(app.SCodo, 'Z')
            app.AMuneca = uiaxes(app.GridLayout2);
            title(app.AMuneca, 'Abduccion Muñeca')
            xlabel(app.AMuneca, 'Frames')
            ylabel(app.AMuneca, 'Grados')
            zlabel(app.AMuneca, 'Z')
            app.FCodo = uiaxes(app.GridLayout2);
            title(app.FCodo, 'Flexion Codo')
            xlabel(app.FCodo, 'Frames')
            ylabel(app.FCodo, 'Grados')
            zlabel(app.FCodo, 'Z')
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.InicioButton = uibutton(app.GridLayout3, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Text = 'Inicio';
            app.PatologiasButton = uibutton(app.GridLayout3, 'push');
            app.PatologiasButton.ButtonPushedFcn = createCallbackFcn(app, @PatologiasButtonPushed, true);
            app.PatologiasButton.Text = 'Patologias';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Graficas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end