classdef GraficasCantidad < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        GridLayout6              matlab.ui.container.GridLayout
        Label_2                  matlab.ui.control.Label
        TiemposLabel             matlab.ui.control.Label
        GridLayout4              matlab.ui.container.GridLayout
        MovimientoLabel          matlab.ui.control.Label
        ContadorVista            matlab.ui.control.NumericEditField
        Label                    matlab.ui.control.Label
        GridLayout3              matlab.ui.container.GridLayout
        StopButton               matlab.ui.control.Button
        ContarButton             matlab.ui.control.Button
        GridLayout2              matlab.ui.container.GridLayout
        ResultadosButton         matlab.ui.control.Button
        IniciarmovimientoButton  matlab.ui.control.Button
        FHombro                  matlab.ui.control.UIAxes
        AHombro                  matlab.ui.control.UIAxes
        SCodo                    matlab.ui.control.UIAxes
        FCodo                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         pintar = 0; % Description
        contador = 0;
        tiempo ; % Description
          t1 ;
          tiempotranscurrido;
    end
    
    
    methods (Access = private)
        function readData(app,src,~)
            data = readline(src);
            data=split(data);
            data =str2double(data);

            src.UserData.Data(size(src.UserData.Data,1)+1,:)= data;

            src.UserData.Count = src.UserData.Count + 1;

            if src.UserData.Count > 100001
                configureCallback(src,"off");
                plot(app.scr.UserData.Data(2:end));
               
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarmovimientoButton
        function IniciarmovimientoButtonPushed(app, event)
           
       app.ContadorVista.Value = 0;
     
     app.pintar=1;
          app.tiempo = 1;
           
            %tiempo de duracion del movimiento
%             maxim= 4;
%             minim= 1;
%             duration = minim+(maxim-minim)*rand(1);

        
            % value = app.TiempoTotal.Value - duration;
%       [xnHF,ynHF,yHFF,xHFF] = AleatoriedadFHombro(app,1, duration,'c')
%       [xnHA,ynHA,yHAF,xHAF] = AleatoriedadAbdHombro(app,1, duration,'c');  
%       [xnCF,ynCF,yCFF,xCFF] = AleatoriedadFCodo(app,1, duration,'c')
%       [xnCS,ynCS,yCSF,xCSF] = AleatoriedadSCodo(app,1, duration,'c')
%       [xnMF,ynMF,yMFF,xMFF] = AleatoriedadFMuneca(app,1, duration,'c')
%       [xnMA,ynMA,yMAF,xMAF] = AleatoriedadAbdMuneca(app,1, duration,'c')
%    
% 
%     maxsize=max([length(yHAF),length(yHFF),length(yMAF),length(yMFF),length(yCFF),length(yCSF)]);
%     i=1;
%     
%     while i<maxsize
%         if i<length(yHFF)
%             plot(app.FHombro,xHFF(1:i),yHFF(1:i))
%         end
%         if i<length(yHAF)
%             plot(app.AHombro,xHAF(1:i),yHAF(1:i))
%         end
%         if i<length(yCFF)
%             plot(app.FCodo,xCFF(1:i),yCFF(1:i))
%         end
%         if i<length(yCSF)
%             plot(app.SCodo,xCSF(1:i),yCSF(1:i))
%         end
%         if i<length(yMFF)
%             plot(app.FMuneca,xMFF(1:i),yMFF(1:i))
%         end
%         if i<length(yMAF)
%             plot(app.AMuneca,xMAF(1:i),yMAF(1:i))
%         end
%         i=i+30;
%         drawnow
%         pause(0.01)
%     end
   if app.tiempo == 1


app.t1 = tic;

   end

 puerto = serialport("COM7",115200);
            configureTerminator(puerto,"CR/LF",1);
            puerto.UserData = struct('Data',[],'Count',1);
            readline(puerto)
            write(puerto, 's','string')
            for i = 1:40
                readline(puerto);
            end
            flush(puerto);
            puerto.UserData.Data=[];
            muestras = 4000;
            x1 = 1:5 :muestras;

            i = 1;


if (app.tiempo == 0)
   
end

             while(app.pintar==1)
                readData(app,puerto);
                x = puerto.UserData.Data(:,1);
                figure(1), plot(x1(1:i),x(1:i),'r');
                drawnow;

                 y = puerto.UserData.Data(:,2);
                
                 z = puerto.UserData.Data(:,3);
               
                i=i+1;
            end


 comprobar = 1;
            if (comprobar ~= 0)
                app.contador = app.contador +1;
            end
            if (app.pintar == 0)
                filename = 'PruebasCantidad.xlsx';

                xlswrite(filename,x,app.contador,'A')
                xlswrite(filename,y,app.contador,'B')
                xlswrite(filename,z,app.contador,'C')


                [num,txt,raw] =    xlsread(filename,app.contador)
                plot(app.FHombro,num(:,1))
                plot(app.AHombro,num(:,2))
                plot(app.FCodo,num(:,1)) %SEGUNDO SENSOR
                plot(app.SCodo,num(:,3)) %SEGUNDO SENSOR


            end


% value = app.TiempoTotal.Value;
%          
%                 value1 = value - duration;
%                 app.TiempoTotal.Value = value1;
% 
%                 movimiento = app.ContadorVista.Value;
%                 movimiento1 = movimiento + 1;
%                 app.ContadorVista.Value = movimiento1;
%     
%                 if (value1 <= 0)
%                   app.TiempoTotal.Value = 0;
%                   app.ContadorVista.Value = movimiento;
%                   
                   %Escribir en un excel

% ID = 1;
% %MAXIMOS  
% maximoHF = max (yHFF);
% maximoHA = max(yHAF);
% maximoCS = max(yCSF);
% maximoCF = max(yCFF);
% maximoMF = max(yMFF);
% maximoMA = max(yMAF);
% 
% TmaximoHF = table (maximoHF);
%                 LmaximoHF = {'Maximo'};
%                 CmaximoHF = table2cell(TmaximoHF);
%                % Concateno en la matriz cell la legenda de cada columna
%                 AmaximoHF = [LmaximoHF;CmaximoHF];
%                 xlswrite('DatosInformeCantidad.xlsx',CmaximoHF,'Hoja1','F2');
%          
% TmaximoHA = table (maximoHA);
%                 LmaximoHA = {'Maximo'};
%                 CmaximoHA = table2cell(TmaximoHA);
%                % Concateno en la matriz cell la legenda de cada columna
%                 AmaximoHA = [LmaximoHA;CmaximoHA];
%                 xlswrite('DatosInformeCantidad.xlsx',CmaximoHA,'Hoja1','F3');
% 
% TmaximoCF = table (maximoCF);
%                 LmaximoCF = {'Maximo'};
%                 CmaximoCF = table2cell(TmaximoCF);
%                % Concateno en la matriz cell la legenda de cada columna
%                 AmaximoCF = [LmaximoCF;CmaximoCF];
%                 xlswrite('DatosInformeCantidad.xlsx',CmaximoCF,'Hoja1','F4');
% 
% TmaximoCS = table (maximoCS);
%                 LmaximoCS = {'Maximo'};
%                 CmaximoCS = table2cell(TmaximoCS);
%                % Concateno en la matriz cell la legenda de cada columna
%                 AmaximoCS = [LmaximoCS;CmaximoCS];
%                 xlswrite('DatosInformeCantidad.xlsx',CmaximoCS,'Hoja1','F5');
% 
%  TmaximoMA = table (maximoMA);
%                 LmaximoMA = {'Maximo'};
%                 CmaximoMA = table2cell(TmaximoMA);
%                % Concateno en la matriz cell la legenda de cada columna
%                 AmaximoMA = [LmaximoMA;CmaximoMA];
%                 xlswrite('DatosInformeCantidad.xlsx',CmaximoMA,'Hoja1','F6');
% 
%  TmaximoMF = table (maximoMF);
%                 LmaximoMF = {'Maximo'};
%                 CmaximoMF = table2cell(TmaximoMF);
%                % Concateno en la matriz cell la legenda de cada columna
%                 AmaximoMF = [LmaximoMF;CmaximoMF];
%                 xlswrite('DatosInformeCantidad.xlsx',CmaximoMF,'Hoja1','F7');
% 
% 
% 
% %MINIMOS  
% minimoHF = min(yHFF);
% minimoHA = min(yHAF);
% minimoCS = min(yCSF);
% minimoCF = min(yCFF);
% minimoMF = min(yMFF);
% minimoMA = min(yMAF);
% 
% TminimoHF = table (minimoHF);
%                 LminimoHF = {'Minimo'};
%                 CminimoHF = table2cell(TminimoHF);
%                % Concateno en la matriz cell la legenda de cada columna
%                 AminimoHF = [LminimoHF;CminimoHF];
%                 xlswrite('DatosInformeCantidad.xlsx',CminimoHF,'Hoja1','G2');
%          
% TminimoHA = table (minimoHA);
%                 LminimoHA = {'Minimo'};
%                 CminimoHA = table2cell(TminimoHA);
%                % Concateno en la matriz cell la legenda de cada columna
%                 AminimoHA = [LminimoHA;CminimoHA];
%                 xlswrite('DatosInformeCantidad.xlsx',CminimoHA,'Hoja1','G3');
% 
% TminimoCF = table (minimoCF);
%                 LminimoCF = {'Minimo'};
%                 CminimoCF = table2cell(TminimoCF);
%                % Concateno en la matriz cell la legenda de cada columna
%                 AminimoCF = [LminimoCF;CminimoCF];
%                 xlswrite('DatosInformeCantidad.xlsx',CminimoCF,'Hoja1','G4');
% 
% TminimoCS = table (minimoCS);
%                 LminimoCS = {'Minimo'};
%                 CminimoCS = table2cell(TminimoCS);
%                % Concateno en la matriz cell la legenda de cada columna
%                 AminimoCS = [LminimoCS;CminimoCS];
%                 xlswrite('DatosInformeCantidad.xlsx',CminimoCS,'Hoja1','G5');
% 
%  TminimoMA = table (minimoMA);
%                 LminimoMA = {'Minimo'};
%                 CminimoMA = table2cell(TminimoMA);
%                % Concateno en la matriz cell la legenda de cada columna
%                 AminimoMA = [LminimoMA;CminimoMA];
%                 xlswrite('DatosInformeCantidad.xlsx',CminimoMA,'Hoja1','G6');
% 
%  TminimoMF = table (minimoMF);
%                 LminimoMF = {'Minimo'};
%                 CminimoMF = table2cell(TminimoMF);
%                % Concateno en la matriz cell la legenda de cada columna
%                 AminimoMF = [LminimoMF;CminimoMF];
%                 xlswrite('DatosInformeCantidad.xlsx',CminimoMF,'Hoja1','G7');
% 
% %RANGOS 
% rangoHF = max (yHFF) - min(yHFF);
% rangoHA = max (yHAF) - min(yHAF);
% rangoCS = max (yCSF) - min(yCSF);
% rangoCF = max (yCFF) - min(yCFF);
% rangoMF = max (yMFF) - min(yMFF);
% rangoMA = max (yMAF) - min(yMAF);
% 
% TrangoHF = table (rangoHF);
%                 LrangoHF = {'Rango'};
%                 CrangoHF = table2cell(TrangoHF);
%                % Concateno en la matriz cell la legenda de cada columna
%                 ArangoHF = [LrangoHF;CrangoHF];
%                 xlswrite('DatosInformeCantidad.xlsx',CrangoHF,'Hoja1','E2');
%          
% TrangoHA = table (rangoHA);
%                 LrangoHA = {'Rango'};
%                 CrangoHA = table2cell(TrangoHA);
%                % Concateno en la matriz cell la legenda de cada columna
%                 ArangoHA = [LrangoHA;CrangoHA];
%                 xlswrite('DatosInformeCantidad.xlsx',CrangoHA,'Hoja1','E3');
% 
% TrangoCF = table (rangoCF);
%                 LrangoCF = {'Rango'};
%                 CrangoCF = table2cell(TrangoCF);
%                % Concateno en la matriz cell la legenda de cada columna
%                 ArangoCF = [LrangoCF;CrangoCF];
%                 xlswrite('DatosInformeCantidad.xlsx',CrangoCF,'Hoja1','E4');
% 
% TrangoCS = table (rangoCS);
%                 LrangoCS = {'Rango'};
%                 CrangoCS = table2cell(TrangoCS);
%                % Concateno en la matriz cell la legenda de cada columna
%                 ArangoCS = [LrangoCS;CrangoCS];
%                 xlswrite('DatosInformeCantidad.xlsx',CrangoCS,'Hoja1','E5');
% 
%  TrangoMA = table (rangoMA);
%                 LrangoMA = {'Rango'};
%                 CrangoMA = table2cell(TrangoMA);
%                % Concateno en la matriz cell la legenda de cada columna
%                 ArangoMA = [LrangoMA;CrangoMA];
%                 xlswrite('DatosInformeCantidad.xlsx',CrangoMA,'Hoja1','E6');
% 
%  TrangoMF = table (rangoMF);
%                 LrangoMF = {'Rango'};
%                 CrangoMF = table2cell(TrangoMF);
%                % Concateno en la matriz cell la legenda de cada columna
%                 ArangoMF = [LrangoMF;CrangoMF];
%                 xlswrite('DatosInformeCantidad.xlsx',CrangoMF,'Hoja1','E7');
% 
% 
% 
% 
% % ID Y MOVIMIENTO 
% 
%                 T = table (ID,movimiento);
%                 L = {'ID','Movimiento'};
%                 C = table2cell(T);
%                % Concateno en la matriz cell la legenda de cada columna
%                 A = [L;C;C;C;C;C;C];
%                 xlswrite('DatosInformeCantidad.xlsx',C,'Hoja1','C2');
%                 xlswrite('DatosInformeCantidad.xlsx',C,'Hoja1','C3');
%                 xlswrite('DatosInformeCantidad.xlsx',C,'Hoja1','C4');
%                 xlswrite('DatosInformeCantidad.xlsx',C,'Hoja1','C5');
%                 xlswrite('DatosInformeCantidad.xlsx',C,'Hoja1','C6');
%                 xlswrite('DatosInformeCantidad.xlsx',C,'Hoja1','C7');
%         

%     end


%  value = app.TiempoTotal.Value - duration;
        end

        % Button pushed function: ResultadosButton
        function ResultadosButtonPushed(app, event)
            Cantidad;
            closereq ();
        end

        % Callback function
        function TiempoTotalValueChanged(app, event)
           
            value = app.TiempoTotal.Value;
            
           % while (app.tiempo)
             %   value1 = value - duration;
             %   app.TiempoTotal.Value = value1;
            %end
        end

        % Button pushed function: ContarButton
        function ContarButtonPushed(app, event)
             movimiento = app.ContadorVista.Value;
                movimiento1 = movimiento + 1;
                app.ContadorVista.Value = movimiento1;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
           close
%tiempotranscurrido = toc(app.t1);
 app.tiempotranscurrido = toc(app.t1);
     value =  string(app.tiempotranscurrido) ;
    set(app.Label_2, 'Text', value);

app.tiempo=0;
            app.pintar = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FCodo = uiaxes(app.GridLayout);
            title(app.FCodo, {'Flexión Codo'; ''})
            xlabel(app.FCodo, 'X')
            ylabel(app.FCodo, 'Y')
            zlabel(app.FCodo, 'Z')
            app.SCodo = uiaxes(app.GridLayout);
            title(app.SCodo, {'Supinación Codo'; ''})
            xlabel(app.SCodo, 'X')
            ylabel(app.SCodo, 'Y')
            zlabel(app.SCodo, 'Z')
            app.AHombro = uiaxes(app.GridLayout);
            title(app.AHombro, {'Abduccióm Hombro'; ''})
            xlabel(app.AHombro, 'X')
            ylabel(app.AHombro, 'Y')
            zlabel(app.AHombro, 'Z')
            app.FHombro = uiaxes(app.GridLayout);
            title(app.FHombro, {'Flexión Hombro'; ''})
            xlabel(app.FHombro, 'X')
            ylabel(app.FHombro, 'Y')
            zlabel(app.FHombro, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.IniciarmovimientoButton = uibutton(app.GridLayout2, 'push');
            app.IniciarmovimientoButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarmovimientoButtonPushed, true);
            app.IniciarmovimientoButton.Text = {'Iniciar movimiento'; ''};
            app.ResultadosButton = uibutton(app.GridLayout2, 'push');
            app.ResultadosButton.ButtonPushedFcn = createCallbackFcn(app, @ResultadosButtonPushed, true);
            app.ResultadosButton.Text = {'Resultados'; ''};
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ContarButton = uibutton(app.GridLayout3, 'push');
            app.ContarButton.ButtonPushedFcn = createCallbackFcn(app, @ContarButtonPushed, true);
            app.ContarButton.Text = {'Contar'; ''};
            app.StopButton = uibutton(app.GridLayout3, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = {'Stop'; ''};
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.Label = uilabel(app.GridLayout4);
            app.Label.Text = '';
            app.ContadorVista = uieditfield(app.GridLayout4, 'numeric');
            app.MovimientoLabel = uilabel(app.GridLayout4);
            app.MovimientoLabel.Text = 'Movimiento:';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.TiemposLabel = uilabel(app.GridLayout6);
            app.TiemposLabel.Text = {'Tiempo (s):'; ''};
            app.Label_2 = uilabel(app.GridLayout6);
            app.Label_2.Text = {'0'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraficasCantidad

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end