classdef GraficasCantidadBorja < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        GridLayout6              matlab.ui.container.GridLayout
        TiempoTotal              matlab.ui.control.NumericEditField
        TiemposLabel             matlab.ui.control.Label
        GridLayout5              matlab.ui.container.GridLayout
        MovimientoLabel          matlab.ui.control.Label
        Contador                 matlab.ui.control.NumericEditField
        Label                    matlab.ui.control.Label
        GridLayout4              matlab.ui.container.GridLayout
        GridLayout3              matlab.ui.container.GridLayout
        ResultadosButton         matlab.ui.control.Button
        GridLayout2              matlab.ui.container.GridLayout
        IniciarmovimientoButton  matlab.ui.control.Button
        FCodo                    matlab.ui.control.UIAxes
        AMuneca                  matlab.ui.control.UIAxes
        FMuneca                  matlab.ui.control.UIAxes
        SCodo                    matlab.ui.control.UIAxes
        AHombro                  matlab.ui.control.UIAxes
        FHombro                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        tiempo; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarmovimientoButton
        function IniciarmovimientoButtonPushed(app, event)
           
       app.Contador.Value = 0;
     app.tiempo=1;
          
           while(app.tiempo) 
            %tiempo de duracion del movimiento
            maxim= 4;
            minim= 1;
            duration = minim+(maxim-minim)*rand(1);
            reps=round(30/duration);

        
            % value = app.TiempoTotal.Value - duration;
      [xnHF,ynHF,yHFF,xHFF] = AleatoriedadFHombro(app,1, duration,'c')
      [xnHA,ynHA,yHAF,xHAF] = AleatoriedadAbdHombro(app,1, duration,'c');  
      [xnCF,ynCF,yCFF,xCFF] = AleatoriedadFCodo(app,1, duration,'c')
      [xnCS,ynCS,yCSF,xCSF] = AleatoriedadSCodo(app,1, duration,'c')
      [xnMF,ynMF,yMFF,xMFF] = AleatoriedadFMuneca(app,1, duration,'c')
      [xnMA,ynMA,yMAF,xMAF] = AleatoriedadAbdMuneca(app,1, duration,'c')
   
    yHAF=repmat(yHAF,reps,1);
    yHFF=repmat(yHFF,reps,1);
    yMAF=repmat(yMAF,reps,1);
    yMFF=repmat(yMFF,reps,1);
    yCFF=repmat(yCFF,reps,1);
    yCSF=repmat(yCSF,reps,1);
    xHAF=linspace(1,30,length(yHAF));
    xHFF=linspace(1,30,length(yHFF));
    xMAF=linspace(1,30,length(yMAF));
    xMFF=linspace(1,30,length(yMFF));
    xCFF=linspace(1,30,length(yCFF));
    xCSF=linspace(1,30,length(yCSF));
    maxsize=max([length(yHAF),length(yHFF),length(yMAF),length(yMFF),length(yCFF),length(yCSF)]);
    i=1;
    paso=round(maxsize/30);
    while i<maxsize
        t1=tic;
        if i<length(yHFF)
            plot(app.FHombro,xHFF(1:i),yHFF(1:i))
        end
        if i<length(yHAF)
            plot(app.AHombro,xHAF(1:i),yHAF(1:i))
        end
        if i<length(yCFF)
            plot(app.FCodo,xCFF(1:i),yCFF(1:i))
        end
        if i<length(yCSF)
            plot(app.SCodo,xCSF(1:i),yCSF(1:i))
        end
        if i<length(yMFF)
            plot(app.FMuneca,xMFF(1:i),yMFF(1:i))
        end
        if i<length(yMAF)
            plot(app.AMuneca,xMAF(1:i),yMAF(1:i))
        end
        i=i+paso;
        drawnow
        t2=toc(t1);
        if(t2<1)
            pause(1-t2)
        end
        
        app.TiempoTotal.Value=app.TiempoTotal.Value-toc(t1);
        app.Contador.Value=app.Contador.Value+1;
    end
     value = app.TiempoTotal.Value;
         
                value1 = value - duration;
                app.TiempoTotal.Value = value1;

                movimiento = app.Contador.Value;
                movimiento1 = movimiento + 1;
                app.Contador.Value = movimiento1;
    
                if (value1 <= 0)
                  app.TiempoTotal.Value = 0;
                  app.Contador.Value = movimiento;
                  
                   %Escribir en un excel

ID = 1;
%MAXIMOS  
maximoHF = max (yHFF);
maximoHA = max(yHAF);
maximoCS = max(yCSF);
maximoCF = max(yCFF);
maximoMF = max(yMFF);
maximoMA = max(yMAF);

TmaximoHF = table (maximoHF);
                LmaximoHF = {'Maximo'};
                CmaximoHF = table2cell(TmaximoHF);
               % Concateno en la matriz cell la legenda de cada columna
                AmaximoHF = [LmaximoHF;CmaximoHF];
                xlswrite('DatosInformeCantidad.xlsx',CmaximoHF,'Hoja1','F2');
         
TmaximoHA = table (maximoHA);
                LmaximoHA = {'Maximo'};
                CmaximoHA = table2cell(TmaximoHA);
               % Concateno en la matriz cell la legenda de cada columna
                AmaximoHA = [LmaximoHA;CmaximoHA];
                xlswrite('DatosInformeCantidad.xlsx',CmaximoHA,'Hoja1','F3');

TmaximoCF = table (maximoCF);
                LmaximoCF = {'Maximo'};
                CmaximoCF = table2cell(TmaximoCF);
               % Concateno en la matriz cell la legenda de cada columna
                AmaximoCF = [LmaximoCF;CmaximoCF];
                xlswrite('DatosInformeCantidad.xlsx',CmaximoCF,'Hoja1','F4');

TmaximoCS = table (maximoCS);
                LmaximoCS = {'Maximo'};
                CmaximoCS = table2cell(TmaximoCS);
               % Concateno en la matriz cell la legenda de cada columna
                AmaximoCS = [LmaximoCS;CmaximoCS];
                xlswrite('DatosInformeCantidad.xlsx',CmaximoCS,'Hoja1','F5');

 TmaximoMA = table (maximoMA);
                LmaximoMA = {'Maximo'};
                CmaximoMA = table2cell(TmaximoMA);
               % Concateno en la matriz cell la legenda de cada columna
                AmaximoMA = [LmaximoMA;CmaximoMA];
                xlswrite('DatosInformeCantidad.xlsx',CmaximoMA,'Hoja1','F6');

 TmaximoMF = table (maximoMF);
                LmaximoMF = {'Maximo'};
                CmaximoMF = table2cell(TmaximoMF);
               % Concateno en la matriz cell la legenda de cada columna
                AmaximoMF = [LmaximoMF;CmaximoMF];
                xlswrite('DatosInformeCantidad.xlsx',CmaximoMF,'Hoja1','F7');


%MINIMOS  
minimoHF = min(yHFF);
minimoHA = min(yHAF);
minimoCS = min(yCSF);
minimoCF = min(yCFF);
minimoMF = min(yMFF);
minimoMA = min(yMAF);

TminimoHF = table (minimoHF);
                LminimoHF = {'Minimo'};
                CminimoHF = table2cell(TminimoHF);
               % Concateno en la matriz cell la legenda de cada columna
                AminimoHF = [LminimoHF;CminimoHF];
                xlswrite('DatosInformeCantidad.xlsx',CminimoHF,'Hoja1','G2');
         
TminimoHA = table (minimoHA);
                LminimoHA = {'Minimo'};
                CminimoHA = table2cell(TminimoHA);
               % Concateno en la matriz cell la legenda de cada columna
                AminimoHA = [LminimoHA;CminimoHA];
                xlswrite('DatosInformeCantidad.xlsx',CminimoHA,'Hoja1','G3');

TminimoCF = table (minimoCF);
                LminimoCF = {'Minimo'};
                CminimoCF = table2cell(TminimoCF);
               % Concateno en la matriz cell la legenda de cada columna
                AminimoCF = [LminimoCF;CminimoCF];
                xlswrite('DatosInformeCantidad.xlsx',CminimoCF,'Hoja1','G4');

TminimoCS = table (minimoCS);
                LminimoCS = {'Minimo'};
                CminimoCS = table2cell(TminimoCS);
               % Concateno en la matriz cell la legenda de cada columna
                AminimoCS = [LminimoCS;CminimoCS];
                xlswrite('DatosInformeCantidad.xlsx',CminimoCS,'Hoja1','G5');

 TminimoMA = table (minimoMA);
                LminimoMA = {'Minimo'};
                CminimoMA = table2cell(TminimoMA);
               % Concateno en la matriz cell la legenda de cada columna
                AminimoMA = [LminimoMA;CminimoMA];
                xlswrite('DatosInformeCantidad.xlsx',CminimoMA,'Hoja1','G6');

 TminimoMF = table (minimoMF);
                LminimoMF = {'Minimo'};
                CminimoMF = table2cell(TminimoMF);
               % Concateno en la matriz cell la legenda de cada columna
                AminimoMF = [LminimoMF;CminimoMF];
                xlswrite('DatosInformeCantidad.xlsx',CminimoMF,'Hoja1','G7');

%RANGOS 
rangoHF = max (yHFF) - min(yHFF);
rangoHA = max (yHAF) - min(yHAF);
rangoCS = max (yCSF) - min(yCSF);
rangoCF = max (yCFF) - min(yCFF);
rangoMF = max (yMFF) - min(yMFF);
rangoMA = max (yMAF) - min(yMAF);

TrangoHF = table (rangoHF);
                LrangoHF = {'Rango'};
                CrangoHF = table2cell(TrangoHF);
               % Concateno en la matriz cell la legenda de cada columna
                ArangoHF = [LrangoHF;CrangoHF];
                xlswrite('DatosInformeCantidad.xlsx',CrangoHF,'Hoja1','E2');
         
TrangoHA = table (rangoHA);
                LrangoHA = {'Rango'};
                CrangoHA = table2cell(TrangoHA);
               % Concateno en la matriz cell la legenda de cada columna
                ArangoHA = [LrangoHA;CrangoHA];
                xlswrite('DatosInformeCantidad.xlsx',CrangoHA,'Hoja1','E3');

TrangoCF = table (rangoCF);
                LrangoCF = {'Rango'};
                CrangoCF = table2cell(TrangoCF);
               % Concateno en la matriz cell la legenda de cada columna
                ArangoCF = [LrangoCF;CrangoCF];
                xlswrite('DatosInformeCantidad.xlsx',CrangoCF,'Hoja1','E4');

TrangoCS = table (rangoCS);
                LrangoCS = {'Rango'};
                CrangoCS = table2cell(TrangoCS);
               % Concateno en la matriz cell la legenda de cada columna
                ArangoCS = [LrangoCS;CrangoCS];
                xlswrite('DatosInformeCantidad.xlsx',CrangoCS,'Hoja1','E5');

 TrangoMA = table (rangoMA);
                LrangoMA = {'Rango'};
                CrangoMA = table2cell(TrangoMA);
               % Concateno en la matriz cell la legenda de cada columna
                ArangoMA = [LrangoMA;CrangoMA];
                xlswrite('DatosInformeCantidad.xlsx',CrangoMA,'Hoja1','E6');

 TrangoMF = table (rangoMF);
                LrangoMF = {'Rango'};
                CrangoMF = table2cell(TrangoMF);
               % Concateno en la matriz cell la legenda de cada columna
                ArangoMF = [LrangoMF;CrangoMF];
                xlswrite('DatosInformeCantidad.xlsx',CrangoMF,'Hoja1','E7');


% ID Y MOVIMIENTO 

                T = table (ID,movimiento);
                L = {'ID','Movimiento'};
                C = table2cell(T);
               % Concateno en la matriz cell la legenda de cada columna
                A = [L;C;C;C;C;C;C];
                xlswrite('DatosInformeCantidad.xlsx',C,'Hoja1','C2');
                xlswrite('DatosInformeCantidad.xlsx',C,'Hoja1','C3');
                xlswrite('DatosInformeCantidad.xlsx',C,'Hoja1','C4');
                xlswrite('DatosInformeCantidad.xlsx',C,'Hoja1','C5');
                xlswrite('DatosInformeCantidad.xlsx',C,'Hoja1','C6');
                xlswrite('DatosInformeCantidad.xlsx',C,'Hoja1','C7');
        break;

    end
           end


%  value = app.TiempoTotal.Value - duration;
        end

        % Button pushed function: ResultadosButton
        function ResultadosButtonPushed(app, event)
            Cantidad;
            closereq ();
        end

        % Value changed function: TiempoTotal
        function TiempoTotalValueChanged(app, event)
           
            value = app.TiempoTotal.Value;
            
           % while (app.tiempo)
             %   value1 = value - duration;
             %   app.TiempoTotal.Value = value1;
            %end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FHombro = uiaxes(app.GridLayout);
            title(app.FHombro, {'Flexión Hombro'; ''})
            xlabel(app.FHombro, 'X')
            ylabel(app.FHombro, 'Y')
            zlabel(app.FHombro, 'Z')
            app.AHombro = uiaxes(app.GridLayout);
            title(app.AHombro, {'Abduccióm Hombro'; ''})
            xlabel(app.AHombro, 'X')
            ylabel(app.AHombro, 'Y')
            zlabel(app.AHombro, 'Z')
            app.SCodo = uiaxes(app.GridLayout);
            title(app.SCodo, {'Supinación Codo'; ''})
            xlabel(app.SCodo, 'X')
            ylabel(app.SCodo, 'Y')
            zlabel(app.SCodo, 'Z')
            app.FMuneca = uiaxes(app.GridLayout);
            title(app.FMuneca, 'Flexión Muñeca')
            xlabel(app.FMuneca, 'X')
            ylabel(app.FMuneca, 'Y')
            zlabel(app.FMuneca, 'Z')
            app.AMuneca = uiaxes(app.GridLayout);
            title(app.AMuneca, {'Abducción Muñeca'; ''})
            xlabel(app.AMuneca, 'X')
            ylabel(app.AMuneca, 'Y')
            zlabel(app.AMuneca, 'Z')
            app.FCodo = uiaxes(app.GridLayout);
            title(app.FCodo, {'Flexión Codo'; ''})
            xlabel(app.FCodo, 'X')
            ylabel(app.FCodo, 'Y')
            zlabel(app.FCodo, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.IniciarmovimientoButton = uibutton(app.GridLayout2, 'push');
            app.IniciarmovimientoButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarmovimientoButtonPushed, true);
            app.IniciarmovimientoButton.Text = {'Iniciar movimiento'; ''};
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ResultadosButton = uibutton(app.GridLayout3, 'push');
            app.ResultadosButton.ButtonPushedFcn = createCallbackFcn(app, @ResultadosButtonPushed, true);
            app.ResultadosButton.Text = {'Resultados'; ''};
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.Label = uilabel(app.GridLayout5);
            app.Label.Text = '';
            app.Contador = uieditfield(app.GridLayout5, 'numeric');
            app.MovimientoLabel = uilabel(app.GridLayout5);
            app.MovimientoLabel.Text = 'Movimiento:';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.TiemposLabel = uilabel(app.GridLayout6);
            app.TiemposLabel.Text = {'Tiempo (s):'; ''};
            app.TiempoTotal = uieditfield(app.GridLayout6, 'numeric');
            app.TiempoTotal.ValueChangedFcn = createCallbackFcn(app, @TiempoTotalValueChanged, true);
            app.TiempoTotal.Value = 30;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraficasCantidadBorja

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end