classdef GraficasTiempo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        GridLayout3              matlab.ui.container.GridLayout
        Transcurrido             matlab.ui.control.Label
        TiempoLabel              matlab.ui.control.Label
        IniciarmovimientoButton  matlab.ui.control.Button
        GridLayout2              matlab.ui.container.GridLayout
        ResultadosButton         matlab.ui.control.Button
        StopButton               matlab.ui.control.Button
        FCodo                    matlab.ui.control.UIAxes
        SCodo                    matlab.ui.control.UIAxes
        AHombro                  matlab.ui.control.UIAxes
        FHombro                  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        pintar = 0; % Description
        contador = 0;
       tiempo ; % Description
          t1 ;
          tiempotranscurrido;
    end

    methods (Access = private)

        function readData(app,src,~)
            data = readline(src);
            data=split(data);
            data =str2double(data);

            src.UserData.Data(size(src.UserData.Data,1)+1,:)= data;

            src.UserData.Count = src.UserData.Count + 1;

            if src.UserData.Count > 100001
                configureCallback(src,"off");
                plot(app.scr.UserData.Data(2:end));
                %     plot(app.SCodo.scr.UserData.Data(2:end));
                %     plot(app.AHombro.scr.UserData.Data(2:end));
                %     plot(app.FHombro.scr.UserData.Data(2:end));
                %     plot(app.AMuneca.scr.UserData.Data(2:end));
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarmovimientoButton
        function IniciarmovimientoButtonPushed(app, event)
            app.pintar = 1;
            app.tiempo = 1;


            %tiempo de duracion del movimiento
            maxim= 4;
            minim= 1;
            duration = minim+(maxim-minim)*rand(1);

            [xnHF,ynHF,yHFF,xHFF] = AleatoriedadFHombro(app,1, duration,'t')
            [xnHA,ynHA,yHAF,xHAF] = AleatoriedadAbdHombro(app,1, duration,'t');
            [xnCF,ynCF,yCFF,xCFF] = AleatoriedadFCodo(app,1, duration,'t')
            [xnCS,ynCS,yCSF,xCSF] = AleatoriedadSCodo(app,1, duration,'t')
            [xnMF,ynMF,yMFF,xMFF] = AleatoriedadFMuneca(app,1, duration,'t')
            [xnMA,ynMA,yMAF,xMAF] = AleatoriedadAbdMuneca(app,1, duration,'t')

 if app.tiempo == 1


app.t1 = tic;

   end

            puerto = serialport("COM7",115200);
            configureTerminator(puerto,"CR/LF",1);
            puerto.UserData = struct('Data',[],'Count',1);
            readline(puerto)
            write(puerto, 's','string')
            for i = 1:40
                readline(puerto);
            end
            flush(puerto);
            puerto.UserData.Data=[];
            muestras = 4000;
            x1 = 1:5 :muestras;

            i = 1;
             while(app.pintar==1)
                readData(app,puerto);
                x = puerto.UserData.Data(:,1);
                figure(1), plot(x1(1:i),x(1:i),'r');
                drawnow;
                %  x = puerto.UserData.Data(:,1);
                %    figure(2),  plot(x1(1:i),x(1:i),'r');
                % drawnow;
                %
                % x = puerto.UserData.Data(:,1);
                %   figure(3),  plot(x2(1:i),x(1:i),'r');
                % drawnow;

                 y = puerto.UserData.Data(:,2);
                % figure(4), plot(x1(1:i),y(1:i),'g');
                % drawnow;

                % y = puerto.UserData.Data(:,2);
                % figure(5), plot(x1(1:i),y(1:i),'g');
                % drawnow;
                %
                 z = puerto.UserData.Data(:,3);
                % figure(6), plot(x1(1:i),z(1:i),'b');
                % drawnow;
                i=i+1;
            end
            %x = puerto.UserData.Data(:,1);

            comprobar = 1;
            if (comprobar ~= 0)
                app.contador = app.contador +1;
            end
            if (app.pintar == 0)
                filename = 'PruebasTiempo.xlsx';

                xlswrite(filename,x,app.contador,'A')
                xlswrite(filename,y,app.contador,'B')
                xlswrite(filename,z,app.contador,'C')


                [num,txt,raw] =    xlsread(filename,app.contador)
                plot(app.FHombro,num(:,1))
                plot(app.AHombro,num(:,2))
                plot(app.FCodo,num(:,1)) %SEGUNDO SENSOR
                plot(app.SCodo,num(:,3)) %SEGUNDO SENSOR


            end

 
%          if (app.tiempo == 0)
%              tiempotranscurrido = toc;
%         end
%            value = 'La duración del movimiento es de ' + string(tiempotranscurrido) ;
%     set(app.TiempoLabel, 'Text', value);

if (app.tiempo == 0)
   
end


            %
            %     while app.pintar == 1
            %
            %         i=i+20;
            %         drawnow
            %         pause(0.01)
            %     end

            %Leemos el excel
%             [num ,x] = LeerExcel(app,'datosHA.xlsx')
%             numT = num;
%             t = x ;
% 
% 
%             %Escribimos en un nuevo excel
% 
% 
%             ID = 1;
%             %MAXIMOS
%             maximoHF = max (yHFF);
%             maximoHA = max(yHAF);
%             maximoCS = max(yCSF);
%             maximoCF = max(yCFF);
%             maximoMF = max(yMFF);
%             maximoMA = max(yMAF);
% 
%             TmaximoHF = table (maximoHF);
%             LmaximoHF = {'Maximo'};
%             CmaximoHF = table2cell(TmaximoHF);
%             % Concateno en la matriz cell la legenda de cada columna
%             AmaximoHF = [LmaximoHF;CmaximoHF];
%             xlswrite('DatosInformeTiempo.xlsx',CmaximoHF,'Hoja1','F2');
% 
%             TmaximoHA = table (maximoHA);
%             LmaximoHA = {'Maximo'};
%             CmaximoHA = table2cell(TmaximoHA);
%             % Concateno en la matriz cell la legenda de cada columna
%             AmaximoHA = [LmaximoHA;CmaximoHA];
%             xlswrite('DatosInformeTiempo.xlsx',CmaximoHA,'Hoja1','F3');
% 
%             TmaximoCF = table (maximoCF);
%             LmaximoCF = {'Maximo'};
%             CmaximoCF = table2cell(TmaximoCF);
%             % Concateno en la matriz cell la legenda de cada columna
%             AmaximoCF = [LmaximoCF;CmaximoCF];
%             xlswrite('DatosInformeTiempo.xlsx',CmaximoCF,'Hoja1','F4');
% 
%             TmaximoCS = table (maximoCS);
%             LmaximoCS = {'Maximo'};
%             CmaximoCS = table2cell(TmaximoCS);
%             % Concateno en la matriz cell la legenda de cada columna
%             AmaximoCS = [LmaximoCS;CmaximoCS];
%             xlswrite('DatosInformeTiempo.xlsx',CmaximoCS,'Hoja1','F5');
% 
%             TmaximoMA = table (maximoMA);
%             LmaximoMA = {'Maximo'};
%             CmaximoMA = table2cell(TmaximoMA);
%             % Concateno en la matriz cell la legenda de cada columna
%             AmaximoMA = [LmaximoMA;CmaximoMA];
%             xlswrite('DatosInformeTiempo.xlsx',CmaximoMA,'Hoja1','F6');
% 
%             TmaximoMF = table (maximoMF);
%             LmaximoMF = {'Maximo'};
%             CmaximoMF = table2cell(TmaximoMF);
%             % Concateno en la matriz cell la legenda de cada columna
%             AmaximoMF = [LmaximoMF;CmaximoMF];
%             xlswrite('DatosInformeTiempo.xlsx',CmaximoMF,'Hoja1','F7');
% 
% 
% 
%             %MINIMOS
%             minimoHF = min(yHFF);
%             minimoHA = min(yHAF);
%             minimoCS = min(yCSF);
%             minimoCF = min(yCFF);
%             minimoMF = min(yMFF);
%             minimoMA = min(yMAF);
% 
%             TminimoHF = table (minimoHF);
%             LminimoHF = {'Minimo'};
%             CminimoHF = table2cell(TminimoHF);
%             % Concateno en la matriz cell la legenda de cada columna
%             AminimoHF = [LminimoHF;CminimoHF];
%             xlswrite('DatosInformeTiempo.xlsx',CminimoHF,'Hoja1','G2');
% 
%             TminimoHA = table (minimoHA);
%             LminimoHA = {'Minimo'};
%             CminimoHA = table2cell(TminimoHA);
%             % Concateno en la matriz cell la legenda de cada columna
%             AminimoHA = [LminimoHA;CminimoHA];
%             xlswrite('DatosInformeTiempo.xlsx',CminimoHA,'Hoja1','G3');
% 
%             TminimoCF = table (minimoCF);
%             LminimoCF = {'Minimo'};
%             CminimoCF = table2cell(TminimoCF);
%             % Concateno en la matriz cell la legenda de cada columna
%             AminimoCF = [LminimoCF;CminimoCF];
%             xlswrite('DatosInformeTiempo.xlsx',CminimoCF,'Hoja1','G4');
% 
%             TminimoCS = table (minimoCS);
%             LminimoCS = {'Minimo'};
%             CminimoCS = table2cell(TminimoCS);
%             % Concateno en la matriz cell la legenda de cada columna
%             AminimoCS = [LminimoCS;CminimoCS];
%             xlswrite('DatosInformeTiempo.xlsx',CminimoCS,'Hoja1','G5');
% 
%             TminimoMA = table (minimoMA);
%             LminimoMA = {'Minimo'};
%             CminimoMA = table2cell(TminimoMA);
%             % Concateno en la matriz cell la legenda de cada columna
%             AminimoMA = [LminimoMA;CminimoMA];
%             xlswrite('DatosInformeTiempo.xlsx',CminimoMA,'Hoja1','G6');
% 
%             TminimoMF = table (minimoMF);
%             LminimoMF = {'Minimo'};
%             CminimoMF = table2cell(TminimoMF);
%             % Concateno en la matriz cell la legenda de cada columna
%             AminimoMF = [LminimoMF;CminimoMF];
%             xlswrite('DatosInformeTiempo.xlsx',CminimoMF,'Hoja1','G7');
% 
%             %RANGOS
%             rangoHF = max (yHFF) - min(yHFF);
%             rangoHA = max (yHAF) - min(yHAF);
%             rangoCS = max (yCSF) - min(yCSF);
%             rangoCF = max (yCFF) - min(yCFF);
%             rangoMF = max (yMFF) - min(yMFF);
%             rangoMA = max (yMAF) - min(yMAF);
% 
%             TrangoHF = table (rangoHF);
%             LrangoHF = {'Rango'};
%             CrangoHF = table2cell(TrangoHF);
%             % Concateno en la matriz cell la legenda de cada columna
%             ArangoHF = [LrangoHF;CrangoHF];
%             xlswrite('DatosInformeTiempo.xlsx',CrangoHF,'Hoja1','E2');
% 
%             TrangoHA = table (rangoHA);
%             LrangoHA = {'Rango'};
%             CrangoHA = table2cell(TrangoHA);
%             % Concateno en la matriz cell la legenda de cada columna
%             ArangoHA = [LrangoHA;CrangoHA];
%             xlswrite('DatosInformeTiempo.xlsx',CrangoHA,'Hoja1','E3');
% 
%             TrangoCF = table (rangoCF);
%             LrangoCF = {'Rango'};
%             CrangoCF = table2cell(TrangoCF);
%             % Concateno en la matriz cell la legenda de cada columna
%             ArangoCF = [LrangoCF;CrangoCF];
%             xlswrite('DatosInformeTiempo.xlsx',CrangoCF,'Hoja1','E4');
% 
%             TrangoCS = table (rangoCS);
%             LrangoCS = {'Rango'};
%             CrangoCS = table2cell(TrangoCS);
%             % Concateno en la matriz cell la legenda de cada columna
%             ArangoCS = [LrangoCS;CrangoCS];
%             xlswrite('DatosInformeTiempo.xlsx',CrangoCS,'Hoja1','E5');
% 
%             TrangoMA = table (rangoMA);
%             LrangoMA = {'Rango'};
%             CrangoMA = table2cell(TrangoMA);
%             % Concateno en la matriz cell la legenda de cada columna
%             ArangoMA = [LrangoMA;CrangoMA];
%             xlswrite('DatosInformeTiempo.xlsx',CrangoMA,'Hoja1','E6');
% 
%             TrangoMF = table (rangoMF);
%             LrangoMF = {'Rango'};
%             CrangoMF = table2cell(TrangoMF);
%             % Concateno en la matriz cell la legenda de cada columna
%             ArangoMF = [LrangoMF;CrangoMF];
%             xlswrite('DatosInformeTiempo.xlsx',CrangoMF,'Hoja1','E7');
% 
% 
% 
% 
%             % ID Y MOVIMIENTO
% 
%             T = table (ID,t);
%             L = {'ID','fallo'};
%             C = table2cell(T);
%             % Concateno en la matriz cell la legenda de cada columna
%             A = [L;C;C;C;C;C;C];
%             xlswrite('DatosInformeTiempo.xlsx',C,'Hoja1','C2');
%             xlswrite('DatosInformeTiempo.xlsx',C,'Hoja1','C3');
%             xlswrite('DatosInformeTiempo.xlsx',C,'Hoja1','C4');
%             xlswrite('DatosInformeTiempo.xlsx',C,'Hoja1','C5');
%             xlswrite('DatosInformeTiempo.xlsx',C,'Hoja1','C6');
%             xlswrite('DatosInformeTiempo.xlsx',C,'Hoja1','C7');


        end

        % Button pushed function: ResultadosButton
        function ResultadosButtonPushed(app, event)
            Tiempo;
            closereq ();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
           close
         app.tiempotranscurrido = toc(app.t1);
     value =  string(app.tiempotranscurrido) ;
    set(app.Transcurrido, 'Text', value);

app.tiempo=0;
            app.pintar = 0;
            %             x = puerto.UserData.Data(:,1);
            %
            %             plot(app.FHombro,x(:),'r');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FHombro = uiaxes(app.GridLayout);
            title(app.FHombro, {'Flexión Hombro'; ''})
            xlabel(app.FHombro, 'X')
            ylabel(app.FHombro, 'Y')
            zlabel(app.FHombro, 'Z')
            app.AHombro = uiaxes(app.GridLayout);
            title(app.AHombro, {'Abduccióm Hombro'; ''})
            xlabel(app.AHombro, 'X')
            ylabel(app.AHombro, 'Y')
            zlabel(app.AHombro, 'Z')
            app.SCodo = uiaxes(app.GridLayout);
            title(app.SCodo, {'Supinación Codo'; ''})
            xlabel(app.SCodo, 'X')
            ylabel(app.SCodo, 'Y')
            zlabel(app.SCodo, 'Z')
            app.SCodo.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.FCodo = uiaxes(app.GridLayout);
            title(app.FCodo, {'Flexión Codo'; ''})
            xlabel(app.FCodo, 'X')
            ylabel(app.FCodo, 'Y')
            zlabel(app.FCodo, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.StopButton = uibutton(app.GridLayout2, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = {'Stop'; ''};
            app.ResultadosButton = uibutton(app.GridLayout2, 'push');
            app.ResultadosButton.ButtonPushedFcn = createCallbackFcn(app, @ResultadosButtonPushed, true);
            app.ResultadosButton.Text = {'Resultados'; ''};
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.IniciarmovimientoButton = uibutton(app.GridLayout3, 'push');
            app.IniciarmovimientoButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarmovimientoButtonPushed, true);
            app.IniciarmovimientoButton.Text = {'Iniciar movimiento'; ''};
            app.TiempoLabel = uilabel(app.GridLayout3);
            app.TiempoLabel.Text = {'Tiempo:'; ''};
            app.Transcurrido = uilabel(app.GridLayout3);
            app.Transcurrido.Text = {'0'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraficasTiempo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end