classdef Informe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        CantidadTab              matlab.ui.container.Tab
        Numero                   matlab.ui.control.Label
        PruebaLabel              matlab.ui.control.Label
        Avanzar                  matlab.ui.control.Button
        Volver                   matlab.ui.control.Button
        VolverButton_3           matlab.ui.control.Button
        ActualizarDatosButton    matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        TiempoTab                matlab.ui.container.Tab
        Numero_2                 matlab.ui.control.Label
        PruebaLabel_2            matlab.ui.control.Label
        Avanzar_2                matlab.ui.control.Button
        Volver_2                 matlab.ui.control.Button
        VolverButton_2           matlab.ui.control.Button
        ActualizarDatosButton_2  matlab.ui.control.Button
        UITable_2                matlab.ui.control.Table
        CalidadTab               matlab.ui.container.Tab
        Volver_3                 matlab.ui.control.Button
        Avanzar_3                matlab.ui.control.Button
        PruebaLabel_3            matlab.ui.control.Label
        Numero_3                 matlab.ui.control.Label
        VolverButton             matlab.ui.control.Button
        ActualizarDatosButton_3  matlab.ui.control.Button
        UITable_3                matlab.ui.control.Table
    end

    
    properties (Access = private)
        contador; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.contador = 1;
             value =  string(app.contador) ;
    set(app.Numero, 'Text', value);
        end

        % Button pushed function: ActualizarDatosButton
        function ActualizarDatosButtonPushed(app, event)
                      [num,txt,raw] = xlsread('DatosInformeCantidad2.xlsx',app.contador)  ;   

%FilaLetra = txt(1,:); % primera fila compuesta por texto
C1_Letra = txt(:,1);  % primera columna compuesta por texto
C2_Letra = txt(:,2);  % segunda columna compuesta por texto
C3_Numero = num(:,1); % primera columna compuesta por numero
C4_Numero = num(:,2); % segunda columna compuesta por numero
C5_Numero = num(:,3); % tercera columna compuesta por numero
C6_Numero = num(:,4); % cuarta columna compuesta por numero
C7_Numero = num(:,5); % quinta columna compuesta por numero

%xlswrite('SacadorDatosCantidad.xls',FilaLetra,'A:F')
xlswrite('SacadorDatosCantidad.xlsx',C1_Letra, 'A2:A5')
xlswrite('SacadorDatosCantidad.xlsx',C2_Letra, 'B2:B5')
xlswrite('SacadorDatosCantidad.xlsx',C3_Numero, 'C2:C5')
xlswrite('SacadorDatosCantidad.xlsx',C4_Numero, 'D2:D5')
xlswrite('SacadorDatosCantidad.xlsx',C5_Numero, 'E2:E5')
xlswrite('SacadorDatosCantidad.xlsx',C6_Numero, 'F2:F5')
xlswrite('SacadorDatosCantidad.xlsx',C7_Numero, 'G2:G5')


            datos  = readtable('SacadorDatosCantidad.xlsx');
            app.UITable.Data = datos;
            columnas = datos.Properties.VariableNames;
            app.UITable.ColumnName = columnas;
        end

        % Button pushed function: ActualizarDatosButton_2
        function ActualizarDatosButton_2Pushed(app, event)
                                [num,txt,raw] = xlsread('DatosInformeTiempo2.xlsx',app.contador)  ;   

%FilaLetra = txt(1,:); % primera fila compuesta por texto
C1_Letra = txt(:,1);  % primera columna compuesta por texto
C2_Letra = txt(:,2);  % segunda columna compuesta por texto
C3_Numero = num(:,1); % primera columna compuesta por numero
C4_Numero = num(:,2); % segunda columna compuesta por numero
C5_Numero = num(:,3); % tercera columna compuesta por numero
C6_Numero = num(:,4); % cuarta columna compuesta por numero
C7_Numero = num(:,5); % quinta columna compuesta por numero

%xlswrite('SacadorDatosCantidad.xls',FilaLetra,'A:F')
xlswrite('SacadorDatosTiempo.xlsx',C1_Letra, 'A2:A5')
xlswrite('SacadorDatosTiempo.xlsx',C2_Letra, 'B2:B5')
xlswrite('SacadorDatosTiempo.xlsx',C3_Numero, 'C2:C5')
xlswrite('SacadorDatosTiempo.xlsx',C4_Numero, 'D2:D5')
xlswrite('SacadorDatosTiempo.xlsx',C5_Numero, 'E2:E5')
xlswrite('SacadorDatosTiempo.xlsx',C6_Numero, 'F2:F5')
xlswrite('SacadorDatosTiempo.xlsx',C7_Numero, 'G2:G5')


            datos  = readtable('SacadorDatosTiempo.xlsx');
            app.UITable_2.Data = datos;
            columnas = datos.Properties.VariableNames;
            app.UITable.ColumnName = columnas;
        end

        % Button pushed function: ActualizarDatosButton_3
        function ActualizarDatosButton_3Pushed(app, event)
 [num,txt,raw] =    xlsread('DatosInformeCalidad2.xlsx',app.contador)  ;   

LabelC = {'ID'};
LabelD = {'Porcentaje de fallo total'};
LabelE = {'Porcentaje de fallo individual'};
LabelF = {'Rango'};
LabelG = {'Máximo'};
LabelH = {'Minimo'};

 %FilaLetra = txt(1,:); % primera fila compuesta por texto
C1_Letra = txt(:,1);  % primera columna compuesta por texto
C2_Letra = txt(:,2);  % segunda columna compuesta por texto
C3_Numero = num(:,1); % primera columna compuesta por numero
C4_Numero = num(:,2); % segunda columna compuesta por numero
C5_Numero = num(:,3); % tercera columna compuesta por numero
C6_Numero = num(:,4); % cuarta columna compuesta por numero
C7_Numero = num(:,5); % quinta columna compuesta por numero
C8_Numero = num(:,6); % sexta columna compuesta por numero

%xlswrite('SacadorDatosCantidad.xls',FilaLetra,'A:F')
xlswrite('SacadorDatosCalidad.xlsx',C1_Letra, 'A1:A5')
xlswrite('SacadorDatosCalidad.xlsx',C2_Letra, 'B1:B5')


xlswrite('SacadorDatosCalidad.xlsx',C3_Numero, 'C2:C5')
xlswrite('SacadorDatosCalidad.xlsx',C4_Numero, 'D2:D5')
xlswrite('SacadorDatosCalidad.xlsx',C5_Numero, 'E2:E5')
xlswrite('SacadorDatosCalidad.xlsx',C6_Numero, 'F2:F5')
xlswrite('SacadorDatosCalidad.xlsx',C7_Numero, 'G2:G5')
xlswrite('SacadorDatosCalidad.xlsx',C8_Numero, 'H2:H5')


            datos  = readtable('SacadorDatosCalidad.xlsx');
            app.UITable_3.Data = datos;
            columnas = datos.Properties.VariableNames;
            app.UITable.ColumnName = columnas;
        end

        % Button pushed function: VolverButton_3
        function VolverButton_3Pushed(app, event)
            Progreso;
             closereq();
        end

        % Button pushed function: VolverButton_2
        function VolverButton_2Pushed(app, event)
            Progreso;
             closereq();
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            Progreso;
             closereq();
        end

        % Button pushed function: Volver
        function VolverPushed(app, event)
            comprobar = 1;
            if comprobar ~= 0
                app.contador = app.contador - 1;
            end
            if app.contador < 1
                app.contador = 1;
            end
                         value =  string(app.contador) ;
    set(app.Numero, 'Text', value);

        end

        % Button down function: CantidadTab
        function CantidadTabButtonDown(app, event)
            
        end

        % Button pushed function: Avanzar
        function AvanzarButtonPushed(app, event)
             comprobar = 1;
            if comprobar ~= 0
                app.contador = app.contador + 1;
            end
                         value =  string(app.contador) ;
    set(app.Numero, 'Text', value);

        end

        % Button pushed function: Avanzar_2
        function Avanzar_2ButtonPushed(app, event)
             comprobar = 1;
            if comprobar ~= 0
                app.contador = app.contador + 1;
            end
                         value =  string(app.contador) ;
    set(app.Numero_2, 'Text', value);
        end

        % Button pushed function: Volver_2
        function Volver_2ButtonPushed(app, event)
            comprobar = 1;
            if comprobar ~= 0
                app.contador = app.contador - 1;
            end
            if app.contador < 1
                app.contador = 1;
            end
                         value =  string(app.contador) ;
    set(app.Numero_2, 'Text', value);
        end

        % Button pushed function: Volver_3
        function Volver_3ButtonPushed(app, event)
            comprobar = 1;
            if comprobar ~= 0
                app.contador = app.contador - 1;
            end
            if app.contador < 1
                app.contador = 1;
            end
                         value =  string(app.contador) ;
    set(app.Numero_3, 'Text', value);
        end

        % Button pushed function: Avanzar_3
        function Avanzar_3ButtonPushed(app, event)
             comprobar = 1;
            if comprobar ~= 0
                app.contador = app.contador + 1;
            end
                         value =  string(app.contador) ;
    set(app.Numero_3, 'Text', value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CantidadTab = uitab(app.TabGroup);
            app.CantidadTab.Title = 'Cantidad ';
            app.CantidadTab.ButtonDownFcn = createCallbackFcn(app, @CantidadTabButtonDown, true);
            app.UITable = uitable(app.CantidadTab);
            app.UITable.ColumnName = {'Métrica'; 'Movimiento'; 'ID'; 'Número de Movimientos'; 'Rango de Movimiento'; 'Máximo'; 'Minimo'};
            app.UITable.RowName = {};
            app.ActualizarDatosButton = uibutton(app.CantidadTab, 'push');
            app.ActualizarDatosButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarDatosButtonPushed, true);
            app.ActualizarDatosButton.Text = 'Actualizar Datos';
            app.VolverButton_3 = uibutton(app.CantidadTab, 'push');
            app.VolverButton_3.ButtonPushedFcn = createCallbackFcn(app, @VolverButton_3Pushed, true);
            app.VolverButton_3.Text = 'Volver';
            app.Volver = uibutton(app.CantidadTab, 'push');
            app.Volver.ButtonPushedFcn = createCallbackFcn(app, @VolverPushed, true);
            app.Volver.Text = '<<<<<';
            app.Avanzar = uibutton(app.CantidadTab, 'push');
            app.Avanzar.ButtonPushedFcn = createCallbackFcn(app, @AvanzarButtonPushed, true);
            app.Avanzar.Text = {'>>>>>'; ''};
            app.PruebaLabel = uilabel(app.CantidadTab);
            app.PruebaLabel.Text = 'Prueba:';
            app.Numero = uilabel(app.CantidadTab);
            app.Numero.Text = '';
            app.TiempoTab = uitab(app.TabGroup);
            app.TiempoTab.Title = 'Tiempo ';
            app.UITable_2 = uitable(app.TiempoTab);
            app.UITable_2.ColumnName = {'Métrica'; 'Movimiento'; 'ID'; 'Tiempo'; 'Rango de Movimiento'; 'Máximo'; 'Minimo'};
            app.UITable_2.RowName = {};
            app.ActualizarDatosButton_2 = uibutton(app.TiempoTab, 'push');
            app.ActualizarDatosButton_2.ButtonPushedFcn = createCallbackFcn(app, @ActualizarDatosButton_2Pushed, true);
            app.ActualizarDatosButton_2.Text = 'Actualizar Datos';
            app.VolverButton_2 = uibutton(app.TiempoTab, 'push');
            app.VolverButton_2.ButtonPushedFcn = createCallbackFcn(app, @VolverButton_2Pushed, true);
            app.VolverButton_2.Text = 'Volver';
            app.Volver_2 = uibutton(app.TiempoTab, 'push');
            app.Volver_2.ButtonPushedFcn = createCallbackFcn(app, @Volver_2ButtonPushed, true);
            app.Volver_2.Text = '<<<<<';
            app.Avanzar_2 = uibutton(app.TiempoTab, 'push');
            app.Avanzar_2.ButtonPushedFcn = createCallbackFcn(app, @Avanzar_2ButtonPushed, true);
            app.Avanzar_2.Text = {'>>>>>'; ''};
            app.PruebaLabel_2 = uilabel(app.TiempoTab);
            app.PruebaLabel_2.Text = 'Prueba:';
            app.Numero_2 = uilabel(app.TiempoTab);
            app.Numero_2.Text = '';
            app.CalidadTab = uitab(app.TabGroup);
            app.CalidadTab.Title = 'Calidad';
            app.UITable_3 = uitable(app.CalidadTab);
            app.UITable_3.ColumnName = {'Métrica'; 'Movimiento'; 'ID'; 'Porcentaje de Fallo Total'; 'Porcentajes de Fallo'; 'Rango de Movimiento'; 'Máximo'; 'Minimo'};
            app.UITable_3.RowName = {};
            app.ActualizarDatosButton_3 = uibutton(app.CalidadTab, 'push');
            app.ActualizarDatosButton_3.ButtonPushedFcn = createCallbackFcn(app, @ActualizarDatosButton_3Pushed, true);
            app.ActualizarDatosButton_3.Text = 'Actualizar Datos';
            app.VolverButton = uibutton(app.CalidadTab, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = {'Volver'; ''};
            app.Numero_3 = uilabel(app.CalidadTab);
            app.Numero_3.Text = '';
            app.PruebaLabel_3 = uilabel(app.CalidadTab);
            app.PruebaLabel_3.Text = 'Prueba:';
            app.Avanzar_3 = uibutton(app.CalidadTab, 'push');
            app.Avanzar_3.ButtonPushedFcn = createCallbackFcn(app, @Avanzar_3ButtonPushed, true);
            app.Avanzar_3.Text = {'>>>>>'; ''};
            app.Volver_3 = uibutton(app.CalidadTab, 'push');
            app.Volver_3.ButtonPushedFcn = createCallbackFcn(app, @Volver_3ButtonPushed, true);
            app.Volver_3.Text = '<<<<<';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Informe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end