classdef Inicio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SimularMovimientoButton    matlab.ui.control.Button
        FinalizarmovimientoButton  matlab.ui.control.Button
        IniciarmovimientoButton    matlab.ui.control.Button
        GraficasButton             matlab.ui.control.Button
        ProgresoButton             matlab.ui.control.Button
        JugarButton                matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
   properties (Access = private)
        pintar;
    end
    methods (Access = public)

        function crearAvatar(app,YPRhombro,YPRcodo,YPRwrist)
           
            f = [1 2 3 4; 5 6 7 8;1 2 6 5;2 3 7 6;3 4 8 7;4 1 5 8];
            color = [1 0 0;0 1 0;0 0 1;1 1 0;1 0 1;1 1 1];
            torso = [30,-10,10;30,40,10;90,40,10;90,-10,10;30,-10,120;30,40,120;90,40,120;90,-10,120];
            brazo = [10,0,60;10,30,60;30,30,60;30,0,60;10,0,120;10,30,120;30,30,120;30,0,120];
            antebrazo = [12,5,10;12,25,10;28,25,10;28,5,10;12,5,60;12,25,60;28,25,60;28,5,60];
            mano = [10,10,-10;10,20,-10;30,20,-10;30,10,-10;10,10,10;10,20,10;30,20,10;30,10,10];
            cuello = [50,0,120;50,30,120;70,30,120;70,0,120;50,0,140;50,30,140;70,30,140;70,0,140];
            cabeza = [30,-10,140;30,40,140;90,40,140;90,-10,140;30,-10,180;30,40,180;90,40,180;90,-10,180];


            Rhombro = rotationMatrix(YPRhombro(1),YPRhombro(2),YPRhombro(3));
            Rcodo = rotationMatrix(YPRcodo(1),YPRcodo(2),YPRcodo(3));
            Rwrist = rotationMatrix(YPRwrist(1),YPRwrist(2),YPRwrist(3));

            %

            manoRotada = applyRotationNx3(applyRotationNx3(applyRotationNx3(mano,...
                sum(antebrazo(1:4,:))/4,Rwrist),sum(brazo(1:4,:))/4,Rcodo),(brazo(7,:)+brazo(8,:))/2,Rhombro);

            %LOS DOS PUNTOS SIGNIFICAN, TODOSS LOS PUNTOS EN LA DIMENSION
            antebrazoRotado = applyRotationNx3(applyRotationNx3(antebrazo,...
                sum(brazo(1:4,:))/4,Rcodo),(brazo(7,:)+brazo(8,:))/2,Rhombro);
            brazoRotado = applyRotationNx3(brazo,...
                (brazo(7,:)+brazo(8,:))/2,Rhombro);

            %LA ROTACION DEL TORSO LA TENGO QUE APLICAR A TODOS MIS COMPONENES
            %EL APPLYROTATION CONSTA DE : MODEL, MATRIZ DE ROTACION Y CENTRO DE ROTACION
            %CENTRO DE ROTACION : SUMO LOS PUNTOS / CANTIDAD DE PUNTOS
            %ENCADENO LOS CENTROS DE ROTACION --> CADENA CINEMATICA

            patch(app.UIAxes,'faces',f,'vertices',brazoRotado,'FaceVertexCData',color,'FaceColor','flat')
            patch(app.UIAxes,'faces',f,'vertices',antebrazoRotado,'FaceVertexCData',color,'FaceColor','flat')
            patch(app.UIAxes,'faces',f,'vertices',manoRotada,'FaceVertexCData',color,'FaceColor','flat')
            patch(app.UIAxes,'faces',f,'vertices',torso,'FaceVertexCData',color,'FaceColor','flat')
            patch(app.UIAxes,'faces',f,'vertices',cuello,'FaceVertexCData',color,'FaceColor','flat')
            patch(app.UIAxes,'faces',f,'vertices',cabeza,'FaceVertexCData',color,'FaceColor','flat')

            view(app.UIAxes,[-5,2])


        end
    end

    methods (Access = public)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

 crearAvatar(app,[0,10,-40],[25,0,-67],[0,5,-27])
        end

        % Button pushed function: JugarButton
        function JugarButtonPushed(app, event)
            metricas;
            closereq ();
        end

        % Button pushed function: GraficasButton
        function GraficasButtonPushed(app, event)
            Graficas;
            closereq ();
        end

        % Button pushed function: IniciarmovimientoButton
        function IniciarmovimientoButtonPushed(app, event)
            
            app.pintar=1;
            while (app.pintar)
           
           
            crearAvatar(app,[0,10,-40],[25,0,-67],[0,5,-27])
            pause(0.5);
            delete(findobj(app.UIAxes,'type', 'patch'));
           
            crearAvatar(app,[0,14,-42],[28,0,-70],[0,6,-25]);
            pause(0.5);
            delete(findobj(app.UIAxes,'type', 'patch'));
           
            crearAvatar(app,[0,19,-45],[35,0,-89],[0,10,-23]);
            pause(0.5);
            delete(findobj(app.UIAxes,'type', 'patch'));

            crearAvatar(app,[0,26,-50],[42,0,-108],[0,12,-20]);
            pause(0.5);
            delete(findobj(app.UIAxes,'type', 'patch'));

             crearAvatar(app,[0,30,-56],[54,0,-115],[0,15,-22]);
            pause(0.5);
            delete(findobj(app.UIAxes,'type', 'patch'));
            
            crearAvatar(app,[0,31,-56],[52,0,-115],[0,15,-21]);
            pause(0.5);
            delete(findobj(app.UIAxes,'type', 'patch'));


            crearAvatar(app,[0,26,-50],[25,0,-107],[0,12,-20]);
            pause(0.5);
            delete(findobj(app.UIAxes,'type', 'patch'));

            crearAvatar(app,[0,21,-45],[17,0,-83],[0,9,-23]);
            pause(0.5);
            delete(findobj(app.UIAxes,'type', 'patch'));

            crearAvatar(app,[0,16,-42],[10,0,-72],[0,6,-25]);
            pause(0.5);
            delete(findobj(app.UIAxes,'type', 'patch'));


            crearAvatar(app,[0,10,-40],[25,0,-67],[0,5,-27]);
            pause(0.5);
       
            if (app.pintar == 0)
                break;
            end
            end
        end

        % Button pushed function: FinalizarmovimientoButton
        function FinalizarmovimientoButtonPushed(app, event)
            app.pintar = 0;
        end

        % Button pushed function: ProgresoButton
        function ProgresoButtonPushed(app, event)
             Progreso;
            closereq ();
        end

        % Button pushed function: SimularMovimientoButton
        function SimularMovimientoButtonPushed(app, event)
             filename = 'MovimientosIdeales.xlsx';
               
a = arduino('COM8');
sHombro =servo(a,'A0'); 
sCodo = servo(a,'A1');
datos = xlsread(filename);
datosFlexionHombro = datos(:,1);
datosFlexionCodo = datos(:,3);


datosFlexionHombro = datosFlexionHombro*1/180;
datosFlexionCodo = datosFlexionCodo*1/180;


for i=1 : length(datosFlexionHombro)

        writePosition(sHombro,datosFlexionHombro(i,1))
        writePosition(sCodo,datosFlexionCodo(i,1))
       i=i+1;
       pause(0.3)
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Avatar'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-10 100];
            app.UIAxes.YLim = [-130 80];
            app.JugarButton = uibutton(app.UIFigure, 'push');
            app.JugarButton.ButtonPushedFcn = createCallbackFcn(app, @JugarButtonPushed, true);
            app.JugarButton.Text = 'Jugar';
            app.ProgresoButton = uibutton(app.UIFigure, 'push');
            app.ProgresoButton.ButtonPushedFcn = createCallbackFcn(app, @ProgresoButtonPushed, true);
            app.ProgresoButton.Text = 'Progreso';
            app.GraficasButton = uibutton(app.UIFigure, 'push');
            app.GraficasButton.ButtonPushedFcn = createCallbackFcn(app, @GraficasButtonPushed, true);
            app.GraficasButton.Text = 'Graficas';
            app.IniciarmovimientoButton = uibutton(app.UIFigure, 'push');
            app.IniciarmovimientoButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarmovimientoButtonPushed, true);
            app.IniciarmovimientoButton.Text = 'Iniciar movimiento';
            app.FinalizarmovimientoButton = uibutton(app.UIFigure, 'push');
            app.FinalizarmovimientoButton.ButtonPushedFcn = createCallbackFcn(app, @FinalizarmovimientoButtonPushed, true);
            app.FinalizarmovimientoButton.Text = {'Finalizar movimiento'; ''};
            app.SimularMovimientoButton = uibutton(app.UIFigure, 'push');
            app.SimularMovimientoButton.ButtonPushedFcn = createCallbackFcn(app, @SimularMovimientoButtonPushed, true);
            app.SimularMovimientoButton.Text = {'Simular Movimiento'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Inicio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end