classdef basicGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        PlayButton   matlab.ui.control.Button
        PauseButton  matlab.ui.control.Button
        StopButton   matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
        UIAxes2      matlab.ui.control.UIAxes
        UIAxes3      matlab.ui.control.UIAxes
        UIAxes4      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        simulation = false; % boolean to plot or not
        start = true;
        hipData = []; % simulated data
        kneeData = []; % simulated data
        ankleData = []; % simulated data
    end

    methods (Access = private)
        
        function results = dibuja(app)         
            while(app.simulation)            
                a = 1024*rand;
                b = 1024*rand;
                c = 1024*rand;
                app.hipData=[app.hipData,a];
                app.kneeData=[app.kneeData,b];
                app.ankleData=[app.ankleData,c];

                plot3(app.UIAxes4,[0:1:size(app.hipData,2)-1],app.hipData,app.kneeData);
                app.UIAxes4.View = [45,30]; 
%                 app.UIAxes4.DataAspectRatioMode = 'manual';
%                 app.UIAxes4.DataAspectRatio = [1 1 1];
%                 app.UIAxes4.XLim = [0 size(app.hipData,2)];
%                 app.UIAxes4.YLim = [0 max(app.hipData)];
%                 app.UIAxes4.ZLim = [0 max(app.kneeData)];
                drawnow;
                                
                if toc > 1
                    plot(app.UIAxes,app.hipData);
                    plot(app.UIAxes2,app.kneeData);
                    plot(app.UIAxes3,app.ankleData);
                    tic
                end
            end
        end     

        function results = func2(app)
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if app.start
                cla(app.UIAxes,'reset');
                cla(app.UIAxes2,'reset');
                cla(app.UIAxes3,'reset');
                cla(app.UIAxes4,'reset');
                app.start = false;
            end
            app.simulation = true;
            tic
            app.dibuja;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            screencapture(app.UIAxes4,[],'myFigure.jpg');
            app.simulation = false;
            app.hipData = [];
            app.kneeData = [];
            app.ankleData = [];
            cla(app.UIAxes,'reset');
            cla(app.UIAxes2,'reset');
            cla(app.UIAxes3,'reset');
            cla(app.UIAxes4,'reset');
            
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.simulation = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [0 0.5 1];
            app.UIAxes.YTick = [0 0.5 1];
            app.UIAxes.ZTick = [0 0.5 1];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2.XLim = [0 1];
            app.UIAxes2.YLim = [0 1];
            app.UIAxes2.ZLim = [0 1];
            app.UIAxes2.CLim = [0 1];
            app.UIAxes2.XTick = [0 0.5 1];
            app.UIAxes2.YTick = [0 0.5 1];
            app.UIAxes2.ZTick = [0 0.5 1];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes3.XLim = [0 1];
            app.UIAxes3.YLim = [0 1];
            app.UIAxes3.ZLim = [0 1];
            app.UIAxes3.CLim = [0 1];
            app.UIAxes3.XTick = [0 0.5 1];
            app.UIAxes3.YTick = [0 0.5 1];
            app.UIAxes3.ZTick = [0 0.5 1];
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes4.XLim = [0 1];
            app.UIAxes4.YLim = [0 1];
            app.UIAxes4.ZLim = [0 1];
            app.UIAxes4.CLim = [0 1];
            app.UIAxes4.XTick = [0 0.5 1];
            app.UIAxes4.YTick = [0 0.5 1];
            app.UIAxes4.ZTick = [0 0.5 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = basicGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end