classdef ejercicio5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FinalizarmovimientoButton  matlab.ui.control.Button
        IniciarmovimientoButton    matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        pintar; % Description
    end
    
    methods (Access = private)
        
        function [x10k,m2] = pintarGrafica(app)
            %Una duracion aleatoria entre 3 y 4,5 segundos
valores = [8,13,20,23,20,15,12,10,9,12,15,27,44,55,63,65,55,44,27,8];
duration = 3.72;

%Creamos un eje x para los valores
eje=linspace(0, duration, length(valores));

var = 0.06*rand(1,length(valores))-0.03;
ejev = eje + var;
ejev(1) = eje (1);
ejev(end)=eje(end);

%pasamos ahora a 10mil puntos
%Creamos un nuevo eje
x10k = linspace(0,duration,10000);

%Ahora interpolamos la señal
pp = makima(ejev, valores);%Se obtiene el polinomio pp que ajusta la curva
m = ppval(pp,x10k); %evaluamos el polinomio en el nuevo eje x

% m pasa a ser la nueva señal de 10000 puntos

% Una componente de ruido de un 5% del rango de la señal (el rango
% valor maximo menos el valor minimo}
%calculamos el rango
rango = max(m) - min(m);

%Añadimos ruido a la señal
ruido = 0.1*rango*rand(1,length(m))-0.05;

%otra forma de hacerlo
%minim = -0.025*rango;
%maxim = 0.025*rango;
%ruido = minim + (maxim-minim)*rand(1,length(m));

m = m + ruido; %se suma el ruido a la nueva señal

%Tomamos ahora las posiciones que nos interesan 

%Si queremos que la señal sea 50Hz, eso significan 50 muestras por segundo 
%50 muestras7seg x 3.72 segundos = 186 muestras 


salto = round(10000/186);

m2 = m(1:salto:end);
plot(app.UIAxes,x10k(1:salto:end),m2)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarmovimientoButton
        function IniciarmovimientoButtonPushed(app, event)
            
         app.pintar=1;   
            [x10k,m2] = pintarGrafica(app);
        while(app.pintar)    
            

 maxsize=max(length(m2));
    i=1;
    while i<maxsize
        if i<length(m2)
            plot(app.UIAxes,x10k(1:i),m(1:i))
        end
       
        i=i+100;
        drawnow
        pause(0.01)
    end

if(app.pintar==0)
    break;
end
       end

        end

        % Button pushed function: FinalizarmovimientoButton
        function FinalizarmovimientoButtonPushed(app, event)
            app.pintar = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.IniciarmovimientoButton = uibutton(app.UIFigure, 'push');
            app.IniciarmovimientoButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarmovimientoButtonPushed, true);
            app.IniciarmovimientoButton.Text = 'Iniciar movimiento';
            app.FinalizarmovimientoButton = uibutton(app.UIFigure, 'push');
            app.FinalizarmovimientoButton.ButtonPushedFcn = createCallbackFcn(app, @FinalizarmovimientoButtonPushed, true);
            app.FinalizarmovimientoButton.Text = 'Finalizar movimiento';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ejercicio5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end